classdef FusAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FusAnalysisUIFigure  matlab.ui.Figure
        Menu                 matlab.ui.container.Menu
        Save                 matlab.ui.container.Menu
        RegisterMap          matlab.ui.container.Panel
        register             matlab.ui.control.Button
        RegionalMap          matlab.ui.container.Panel
        time_regional        matlab.ui.control.Button
        RTClim               matlab.ui.control.EditField
        Label_6              matlab.ui.control.Label
        CorrelationPanel     matlab.ui.container.Panel
        CorrelationClim      matlab.ui.control.EditField
        Label_3              matlab.ui.control.Label
        CorrelationRun       matlab.ui.control.Button
        CorrelationT2        matlab.ui.control.EditField
        Label_2              matlab.ui.control.Label
        CorrelationT1        matlab.ui.control.EditField
        Label                matlab.ui.control.Label
    end

    properties (Access = public)
        folderpath char = [];
        code_path char = [];
        trans_path char = [];
        region_path = [];
        result_path char = [];
        transfR = 0;
        fus_datas = 0;
        atlas = 0;
        atlas_plane = 0;
        filter_datas = 0;
        correlationMap= 0;
        regionTimeMap = 0;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            mlappPath = fileparts(fileparts(mfilename('fullpath')));
            app.code_path = strcat(mlappPath,'\DataAnalysis\FUS\code');
            genpath(app.code_path);
            app.trans_path = strcat(mlappPath,'\DataAnalysis\FUS\Transformation');
            genpath( app.trans_path);
            app.region_path = strcat(mlappPath,'\DataAnalysis\FUS\SelectedRegions');
            genpath( app.region_path);
            app.result_path = strcat(mlappPath,'\DataAnalysis\Result');

        end

        % Button pushed function: register
        function registerPushed(app, event)
            app.folderpath = uigetdir('D:\');
            if(app.folderpath==0)
                return;
            end
            eval( strcat('load' ,32,app.folderpath,'\fus_data.mat'));
            
            app.fus_datas = fus_data;
            eval( strcat('load' ,32,app.code_path,'\mouse_brain_atlas.mat'));
           
            app.atlas = atlas;
            eval( strcat('load' ,32,app.trans_path,'\Transformation.mat'));
      
            idx = round(size( app.fus_datas.Data,3)/2);
            if(idx<1)
                idx =1;
            elseif(idx>size( app.fus_datas.Data,3))
                idx = size( app.fus_datas.Data,3);
            else
                idx = 1;
            end
            app.CorrelationT1.Value = '1';
            app.CorrelationT2.Value = num2str(size( app.fus_datas.Data,3));

            anatomic.Data =  app.fus_datas.Data(:,:,idx);
            anatomic.Direction= 'DV.LR';
            anatomic.Type = 'plane';
            anatomic.VoxelSize =  app.fus_datas.VoxelSize(1:2);
            manual_registration(app.atlas,anatomic,app.result_path,Transf);
            eval( strcat('load' ,32,app.result_path,'\Transformation.mat'));
            app.transfR =  Transf;


        end

        % Button pushed function: CorrelationRun
        function CorrelationRunButtonPushed(app, event)
            eval( strcat('load' ,32,app.result_path,'\Transformation.mat'));
            app.transfR =  Transf;
            app.filter_datas =  app.fus_datas;
            filterd = outlier_filter(app.fus_datas);
            app.filter_datas.Data = filterd.Data;
            T1 = str2double(app.CorrelationT1.Value);
            T2 = str2double(app.CorrelationT2.Value);
            mapOriginal=Correlatio_map(app.filter_datas, T1, T2);
            % mapOriginal.Data = double(filter_datas.Data(:,:,1));
            PlaneCor= app.transfR.Plane;   % Coronal plane

            atlas_new = app.atlas;

            atlas_new.Histology = atlas_new.Histology(:,:,PlaneCor);
            atlas_new.Regions    =     atlas_new.Regions(:,:,PlaneCor);
            atlas_new.Vascular   =    atlas_new.Vascular (:,:,PlaneCor);
            atlas_new.VoxelSize  =    atlas_new.VoxelSize(1:2);
            atlas_new.Direction = 'DV.LR';
            app.atlas_plane = atlas_new;
            mapRegistered  = register_Data(atlas_new,mapOriginal,app.transfR);
            app.correlationMap = mapRegistered;
            % Coronal view
            figure(101)
            imagesc(squeeze(mapRegistered));
            drawBorders(atlas_new,'coronal',PlaneCor);
            TITLE = sprintf('Correlation Map');title (TITLE);
            cclimv = str2num(app.CorrelationClim.Value);
            clim(cclimv);
            axis equal; axis tight;
            colormap jet;
            colorbar


        end

        % Button pushed function: time_regional
        function time_regionalButtonPushed(app, event)
            load(strcat(app.result_path,'\Transformation.mat'),'Transf');
            app.transfR =  Transf;
            Lut=SegLut2D(app.transfR, app.atlas_plane,app.filter_datas);
            scanfusSegmented=segmentation2D(Lut,app.filter_datas);
            % Selection of a subset of brain regions listed in 'list_selected_regions.txt'.
            regionSelection=selectBrainRegions(app.atlas_plane,strcat(app.region_path,'\listSelectedRegions.txt'),scanfusSegmented);

            figure(102);
            % in this example we averaged both hemispheres
            % and normalize as Z score with 30 points in the baseline.
            regionTotal=regionSelection.Left+regionSelection.Right;
            nbaseline= round(size(regionTotal,2)/2);  % 30 points of baseline
            if(nbaseline<1)
                nbaseline=1;
            elseif(nbaseline>size(regionTotal,2))
                nbaseline = size(regionTotal,2);
            end
            regionTotal(isnan(regionTotal)) = 0;

      
            for ir=1:size(regionTotal,1)
                regionTotal(ir,:)=(regionTotal(ir,:)-mean(regionTotal(ir,1:nbaseline),2))/(std(regionTotal(ir,1:nbaseline),[],2)+1e-10);
            end
            app.regionTimeMap = regionTotal;
            imagesc(regionTotal);
            title (' z-score ');
            xlabel('Time (image)');
            ylabel('Regions')
            cclimv = str2num(app.RTClim.Value);    
            clim(cclimv);
            colormap (jet)
            % axis('tight','equal')
            colorbar

        end

        % Menu selected function: Save
        function SaveSelected(app, event)
          RegionTimeMap  =  app.regionTimeMap;
          eval( strcat('save' ,32,app.result_path,'\RegionTimeMap.mat'));

          CorrelationMap  =  app.correlationMap;
          eval( strcat('save' ,32,app.result_path,'\CorrelationMap.mat'));   
          ls = strcat(app.region_path,'\listSelectedRegions.txt');
          printRegionList(app.atlas,app.result_path,ls);
 
          printRegionList(app.atlas,app.result_path);
          disp(strcat('The file has been saved in:',32,app.result_path))

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FusAnalysisUIFigure = uifigure('Visible', 'off');
            app.FusAnalysisUIFigure.Name = 'FusAnalysis';
            app.Menu = uimenu(app.FusAnalysisUIFigure);
            app.Menu.Text = '文件';
            app.Save = uimenu(app.Menu);
            app.Save.MenuSelectedFcn = createCallbackFcn(app, @SaveSelected, true);
            app.Save.Text = '保存';
            app.CorrelationPanel = uipanel(app.FusAnalysisUIFigure);
            app.CorrelationPanel.Title = '2、相关性图';
            app.Label = uilabel(app.CorrelationPanel);
            app.Label.Text = '起始';
            app.CorrelationT1 = uieditfield(app.CorrelationPanel, 'text');
            app.CorrelationT1.Value = '1';
            app.Label_2 = uilabel(app.CorrelationPanel);
            app.Label_2.Text = '结束';
            app.CorrelationT2 = uieditfield(app.CorrelationPanel, 'text');
            app.CorrelationT2.Value = '10';
            app.CorrelationRun = uibutton(app.CorrelationPanel, 'push');
            app.CorrelationRun.ButtonPushedFcn = createCallbackFcn(app, @CorrelationRunButtonPushed, true);
            app.CorrelationRun.Text = '相关性图';
            app.Label_3 = uilabel(app.CorrelationPanel);
            app.Label_3.Text = 'clim';
            app.CorrelationClim = uieditfield(app.CorrelationPanel, 'text');
            app.CorrelationClim.Value = '-0.4    0.4';
            app.RegionalMap = uipanel(app.FusAnalysisUIFigure);
            app.RegionalMap.Title = '3、时间-区域图';
            app.Label_6 = uilabel(app.RegionalMap);
            app.Label_6.Text = 'clim';
            app.RTClim = uieditfield(app.RegionalMap, 'text');
            app.RTClim.Value = '-6           6';
            app.time_regional = uibutton(app.RegionalMap, 'push');
            app.time_regional.ButtonPushedFcn = createCallbackFcn(app, @time_regionalButtonPushed, true);
            app.time_regional.Text = '时间-区域图';
            app.RegisterMap = uipanel(app.FusAnalysisUIFigure);
            app.RegisterMap.Title = '1、配准';
            app.register = uibutton(app.RegisterMap, 'push');
            app.register.ButtonPushedFcn = createCallbackFcn(app, @registerPushed, true);
            app.register.Text = '配准';
            % Show the figure after all components are created
            app.FusAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FusAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FusAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FusAnalysisUIFigure)
        end
    end
end