classdef SignalProcess < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SignalProcessUIFigure        matlab.ui.Figure
        Menu_Parameters              matlab.ui.container.Menu
        SaveADC                      matlab.ui.container.Menu
        SaveRF                       matlab.ui.container.Menu
        SaveB                        matlab.ui.container.Menu
        LoadPara_UPW                 matlab.ui.container.Menu
        FusDataAnaly                 matlab.ui.container.Menu
        TabGroup                     matlab.ui.container.TabGroup
        BTab                         matlab.ui.container.Tab
        AllmagShow                   matlab.ui.container.Panel
        FreSpectrumCLabel            matlab.ui.control.Label
        FreSpectrumChannel           matlab.ui.control.EditField
        Label_3                      matlab.ui.control.Label
        FreSpectrumSwitch            matlab.ui.control.CheckBox
        FreSpectrumDRange            matlab.ui.control.EditField
        Label_2                      matlab.ui.control.Label
        FreSpectrumLRange            matlab.ui.control.EditField
        Label                        matlab.ui.control.Label
        ImageCheck                   matlab.ui.control.CheckBox
        ImageLoc                     matlab.ui.control.EditField
        ImgaeSlider                  matlab.ui.control.Slider
        ImageType                    matlab.ui.control.DropDown
        ImageShow                    matlab.ui.control.UIAxes
        FreSpectrumImage             matlab.ui.control.UIAxes
        Post_Process_Parameters      matlab.ui.container.Panel
        Demodulation_Parameters      matlab.ui.container.Panel
        FilterType                   matlab.ui.control.DropDown
        Label_29                     matlab.ui.control.Label
        FilterCoef                   matlab.ui.control.EditField
        EditField_12Label_2          matlab.ui.control.Label
        FilterOrder                  matlab.ui.control.EditField
        EditField_11Label_2          matlab.ui.control.Label
        DemodulationValue            matlab.ui.control.EditField
        EditField_12Label            matlab.ui.control.Label
        DemodulationDepth            matlab.ui.control.EditField
        EditField_11Label            matlab.ui.control.Label
        Gain_Parameters              matlab.ui.container.Panel
        DigitalGainValue             matlab.ui.control.EditField
        Label_28                     matlab.ui.control.Label
        DigitalGainDepth             matlab.ui.control.EditField
        EditField_17Label            matlab.ui.control.Label
        GlobalDigitalGain            matlab.ui.control.EditField
        Label_27                     matlab.ui.control.Label
        KnobGain                     matlab.ui.control.Knob
        TGC_Parameters               matlab.ui.container.Panel
        SliderGain5                  matlab.ui.control.Slider
        SliderGain4                  matlab.ui.control.Slider
        SliderGain3                  matlab.ui.control.Slider
        SliderGain2                  matlab.ui.control.Slider
        SliderGain1                  matlab.ui.control.Slider
        SliderGain0                  matlab.ui.control.Slider
        PostProcessRun               matlab.ui.control.Button
        DscFlag                      matlab.ui.control.CheckBox
        Label_26                     matlab.ui.control.Label
        TimtMapIdx                   matlab.ui.control.DropDown
        Label_25                     matlab.ui.control.Label
        Label_24                     matlab.ui.control.Label
        GrayMapIdx                   matlab.ui.control.DropDown
        DynamicRange                 matlab.ui.control.EditField
        Label_23                     matlab.ui.control.Label
        SpatialSmoothLevel           matlab.ui.control.DropDown
        Label_22                     matlab.ui.control.Label
        Frontend                     matlab.ui.container.Panel
        PlaneWaveImageRegion         matlab.ui.container.Panel
        ImageRegion_PlaneWave_Auto   matlab.ui.control.CheckBox
        X_Panel                      matlab.ui.container.Panel
        Pw_step_x                    matlab.ui.control.EditField
        EditField_9Label_34          matlab.ui.control.Label
        Pw_end_x                     matlab.ui.control.EditField
        EditField_9Label_33          matlab.ui.control.Label
        Pw_start_x                   matlab.ui.control.EditField
        EditField_9Label_32          matlab.ui.control.Label
        Z_Panel                      matlab.ui.container.Panel
        Pw_step_z                    matlab.ui.control.EditField
        EditField_9Label_31          matlab.ui.control.Label
        Pw_end_z                     matlab.ui.control.EditField
        EditField_9Label_30          matlab.ui.control.Label
        Pw_start_z                   matlab.ui.control.EditField
        EditField_9Label_29          matlab.ui.control.Label
        Stop                         matlab.ui.control.Button
        Run                          matlab.ui.control.Button
        EmitFocusLabel               matlab.ui.control.Label
        EmitPlaneAngleLabel          matlab.ui.control.Label
        MachineChannel               matlab.ui.control.EditField
        Label_14                     matlab.ui.control.Label
        EmitPlaneAngle               matlab.ui.control.EditField
        BeamType                     matlab.ui.control.DropDown
        Label_12                     matlab.ui.control.Label
        CaptureFrames                matlab.ui.control.EditField
        Label_11                     matlab.ui.control.Label
        CaptureSpeed                 matlab.ui.control.EditField
        Label_10                     matlab.ui.control.Label
        SampleRate                   matlab.ui.control.EditField
        Label_9                      matlab.ui.control.Label
        SoundSpeed                   matlab.ui.control.EditField
        Label_8                      matlab.ui.control.Label
        Probe                        matlab.ui.control.DropDown
        Label_7                      matlab.ui.control.Label
        Receive_Aperture_Parameters  matlab.ui.container.Panel
        MaxRevAper                   matlab.ui.control.EditField
        EditField_9Label_5           matlab.ui.control.Label
        MinRevAper                   matlab.ui.control.EditField
        EditField_9Label_4           matlab.ui.control.Label
        RevApodWin                   matlab.ui.control.DropDown
        Label_21                     matlab.ui.control.Label
        RevAperFnum                  matlab.ui.control.EditField
        FEditField_3Label            matlab.ui.control.Label
        RevAperDepth                 matlab.ui.control.EditField
        Label_20                     matlab.ui.control.Label
        ScanLines                    matlab.ui.control.EditField
        Emit_Aperture_Parameters     matlab.ui.container.Panel
        MaxEmitAper                  matlab.ui.control.EditField
        EditField_9Label_3           matlab.ui.control.Label
        MinEmitAper                  matlab.ui.control.EditField
        EditField_9Label_2           matlab.ui.control.Label
        EmitAperFnum                 matlab.ui.control.EditField
        FEditField_4Label            matlab.ui.control.Label
        EmitAperDepth                matlab.ui.control.EditField
        EditField_9Label             matlab.ui.control.Label
        Label_6                      matlab.ui.control.Label
        ImageDepth                   matlab.ui.control.EditField
        Label_5                      matlab.ui.control.Label
        EmitFocus                    matlab.ui.control.EditField
        Emit_Wave_Parameters         matlab.ui.container.Panel
        EmitPolarity                 matlab.ui.control.EditField
        Label_19                     matlab.ui.control.Label
        EmitPulseFre                 matlab.ui.control.EditField
        Label_18                     matlab.ui.control.Label
        EmitDuration                 matlab.ui.control.EditField
        Label_17                     matlab.ui.control.Label
        EmitPulseNum                 matlab.ui.control.EditField
        Label_16                     matlab.ui.control.Label
        EmitVol                      matlab.ui.control.EditField
        Label_15                     matlab.ui.control.Label
        FUSTab                       matlab.ui.container.Tab
        AllmagShow_UPW               matlab.ui.container.Panel
        ImageShow_UPW_2              matlab.ui.control.UIAxes
        ImageShow_UPW                matlab.ui.control.UIAxes
        Post_Process_Parameters_UPW  matlab.ui.container.Panel
        UPWColorROIPanel             matlab.ui.container.Panel
        ColorROI_UPW_TextArea        matlab.ui.control.TextArea
        Label_65                     matlab.ui.control.Label
        ColorROI_UPW_Check           matlab.ui.control.CheckBox
        BF_Parameters_UPW            matlab.ui.container.Panel
        Run_BF_UPW                   matlab.ui.control.Button
        Label_64                     matlab.ui.control.Label
        BF_IQ_UPW_Check              matlab.ui.control.CheckBox
        BF_Data_PackSize_UPW         matlab.ui.control.EditField
        EditField_11Label_9          matlab.ui.control.Label
        BF_FrameEnd_UPW              matlab.ui.control.EditField
        EditField_11Label_8          matlab.ui.control.Label
        BF_FrameStart_UPW            matlab.ui.control.EditField
        EditField_11Label_7          matlab.ui.control.Label
        SVD_Parameters_UPW           matlab.ui.container.Panel
        Label_66                     matlab.ui.control.Label
        SavePow_UPW                  matlab.ui.control.CheckBox
        Run_SVD_UPW                  matlab.ui.control.Button
        Svd_Packsize                 matlab.ui.control.EditField
        EditField_11Label_10         matlab.ui.control.Label
        Svd_H                        matlab.ui.control.EditField
        EditField_11Label_6          matlab.ui.control.Label
        Label_63                     matlab.ui.control.Label
        Svd_Check                    matlab.ui.control.CheckBox
        Svd_L                        matlab.ui.control.EditField
        EditField_11Label_5          matlab.ui.control.Label
        Demodulation_Parameters_UPW  matlab.ui.container.Panel
        FilterType_UPW               matlab.ui.control.DropDown
        Label_62                     matlab.ui.control.Label
        FilterCoef_UPW               matlab.ui.control.EditField
        EditField_12Label_4          matlab.ui.control.Label
        FilterOrder_UPW              matlab.ui.control.EditField
        EditField_11Label_4          matlab.ui.control.Label
        DemodulationValue_UPW        matlab.ui.control.EditField
        EditField_12Label_3          matlab.ui.control.Label
        DemodulationDepth_UPW        matlab.ui.control.EditField
        EditField_11Label_3          matlab.ui.control.Label
        PostProcessRun_UPW           matlab.ui.control.Button
        DynamicRange_UPW             matlab.ui.control.EditField
        Label_56                     matlab.ui.control.Label
        Frontend_UPW                 matlab.ui.container.Panel
        FrameRate_UPW                matlab.ui.control.EditField
        Label_54                     matlab.ui.control.Label
        Label_53                     matlab.ui.control.Label
        ShowImg_UPW                  matlab.ui.control.CheckBox
        Label_52                     matlab.ui.control.Label
        SaveADC_UPW                  matlab.ui.control.CheckBox
        Run_UPW                      matlab.ui.control.Button
        Receive_Aperture_Parameters_UPW  matlab.ui.container.Panel
        RevAperDepth_UPW             matlab.ui.control.EditField
        FEditField_3Label_3          matlab.ui.control.Label
        MaxRevAper_UPW               matlab.ui.control.EditField
        EditField_9Label_13          matlab.ui.control.Label
        MinRevAper_UPW               matlab.ui.control.EditField
        EditField_9Label_12          matlab.ui.control.Label
        RevApodWin_UPW               matlab.ui.control.DropDown
        Label_50                     matlab.ui.control.Label
        RevAperFnum_UPW              matlab.ui.control.EditField
        FEditField_3Label_2          matlab.ui.control.Label
        EmitPlaneAngle_UPW           matlab.ui.control.EditField
        Label_42                     matlab.ui.control.Label
        ImageDepth_UPW               matlab.ui.control.EditField
        Label_34                     matlab.ui.control.Label
        CaptureSpeed_UPW             matlab.ui.control.EditField
        Label_39                     matlab.ui.control.Label
        Label_51                     matlab.ui.control.Label
        CaptureTimes_UPW             matlab.ui.control.EditField
        Emit_Wave_Parameters_UPW     matlab.ui.container.Panel
        EmitPolarity_UPW             matlab.ui.control.EditField
        Label_48                     matlab.ui.control.Label
        EmitPulseFre_UPW             matlab.ui.control.EditField
        Label_47                     matlab.ui.control.Label
        EmitDuration_UPW             matlab.ui.control.EditField
        Label_46                     matlab.ui.control.Label
        EmitPulseNum_UPW             matlab.ui.control.EditField
        Label_45                     matlab.ui.control.Label
        EmitVol_UPW                  matlab.ui.control.EditField
        Label_44                     matlab.ui.control.Label
        MachineChannel_UPW           matlab.ui.control.EditField
        Label_43                     matlab.ui.control.Label
        PlaneWaveImageRegion_UPW     matlab.ui.container.Panel
        ImageRegion_UPW_Auto         matlab.ui.control.CheckBox
        UPW_X_Panel                  matlab.ui.container.Panel
        Pw_step_x_UPW                matlab.ui.control.EditField
        EditField_9Label_22          matlab.ui.control.Label
        Pw_end_x_UPW                 matlab.ui.control.EditField
        EditField_9Label_21          matlab.ui.control.Label
        Pw_start_x_UPW               matlab.ui.control.EditField
        EditField_9Label_20          matlab.ui.control.Label
        UPW_Z_Panel                  matlab.ui.container.Panel
        Pw_step_z_UPW                matlab.ui.control.EditField
        EditField_9Label_19          matlab.ui.control.Label
        Pw_end_z_UPW                 matlab.ui.control.EditField
        EditField_9Label_18          matlab.ui.control.Label
        Pw_start_z_UPW               matlab.ui.control.EditField
        EditField_9Label_14          matlab.ui.control.Label
        SampleRate_UPW               matlab.ui.control.EditField
        Label_38                     matlab.ui.control.Label
        SoundSpeed_UPW               matlab.ui.control.EditField
        Label_37                     matlab.ui.control.Label
        Probe_UPW                    matlab.ui.control.DropDown
        Label_36                     matlab.ui.control.Label
    end


    properties (Access = public)
        Channel = 128;
        sos = 1540;
        SysRate  = 40e6;
        EmitBeamType  = 'Focus';
        PortType = 1;
        AcqConfig= [];
        ScanList = [];
        dev_manager;
        AdcData = [];
        RfData = [];
        IqData = [];
        BData = [];
        bimg_no_dsc = [];
        bimg_dsc = [];
        IsRunning logical = false;
        currentPath char = 'D:\'
        AdcMap;
        postprocess;
        IsAdcImg logical = true;
        IsRfImg logical = false;
        IsBImg logical = false;
        startpt = [];
        endpt = [];
        Revpt = [];
        Revdepth = [];
        dsc_height = 480;
        dsc_width = 640;
        capturespeed = 1000;
        pre_frame_id = -1;
        capturetimes = 1000;
        upw_folderPath char = [];
        plane_wave_folderPath char = [];
        upw_BFPath char = [];
        upw_DOPPLERPath char = [];
        framerate= 35;

        framerate_upw = 35;
        frame_nums = 1;
        NumsPerFile = 1;
        revloc_upw = [];
        modetype char = 'B';
        RectROI;
        RoiListeners;
        ImgHandle      % imshow 返回的图像句柄
        power_dopplers
    end

    methods (Access = private)

        function update_paras(app)
            %%          添加路径
            app.currentPath = pwd;
            folders = genpath(app.currentPath);
            addpath(folders);
            app.AdcData = [];
            app.RfData = [];
            app.IqData = [];
            %%          配置全局参数
            filePath = fullfile(app.currentPath, 'function', 'AdcMap.mat');
            if isfile(filePath)
                temp  = load(filePath);
                app.AdcMap  = temp.AdcMap;
            end

            if(strcmp(app.modetype,'B'))

                app.EmitBeamType = app.BeamType.Value;
                app.SysRate = str2double(app.SampleRate.Value);
                app.AcqConfig.Tx.fs = app.SysRate;
                app.AcqConfig.Tx.sos = str2double(app.SoundSpeed.Value);
                app.sos = app.AcqConfig.Tx.sos ;
                app.Channel = str2double(app.MachineChannel.Value);
                if(app.Channel==128)
                    app.PortType = 1;
                else
                    app.PortType = 3;
                end
                app.AcqConfig.Tx.channel = app.Channel;
                focus_depth = str2double(app.EmitFocus.Value);
                app.AcqConfig.Tx.focus_depth =  focus_depth;
                app.capturespeed = str2double(app.CaptureSpeed.Value);
                %%          配置探头参数
                probe_name = app.Probe.Value;
                probe =  Probe_para(probe_name);
                app.AcqConfig.Probe = probe;
                %%          采样点数
                imagedepth = str2double(app.ImageDepth.Value);
                [Rx,app.Revpt,app.Revdepth ]= SampleInfo(app.AcqConfig,imagedepth);
                app.AcqConfig.Rx = Rx;
                app.AcqConfig.Rx.Revpt = app.Revpt;
                app.AcqConfig.Rx.Revdepth = app.Revdepth;
                app.AcqConfig.Rx.WaveType = app.BeamType.Value;
                if(strcmp(app.BeamType.Value,'Focus Wave'))
                    %%          发射、接收线位置
                    scanLine = str2double(app.ScanLines.Value);
                    [emit_line,rev_line] = FsJsLineLoc( app.AcqConfig.Probe,scanLine);
                    app.AcqConfig.Tx.emit_line = emit_line;
                    app.AcqConfig.Rx.rev_line = rev_line;


                    %%          发射孔径
                    tx_aper.max_Aper = str2double(app.MaxEmitAper.Value);
                    tx_aper.min_Aper = str2double(app.MinEmitAper.Value);
                    tx_aper.fn_value = str2double(strsplit(app.EmitAperFnum.Value));
                    tx_aper.fn_depth = str2double(strsplit(app.EmitAperDepth.Value));
                    app.AcqConfig.Tx.aperture = Aperature(tx_aper, app.AcqConfig.Tx.focus_depth,probe,app.Channel);

                end
                %%          发射波形
                txwave.voltage = str2double(app.EmitVol.Value);
                txwave.pulse_num = str2double(app.EmitPulseNum.Value);
                txwave.pulse_duration = str2double(app.EmitDuration.Value);
                txwave.pulse_frequency = str2double(app.EmitPulseFre.Value);
                txwave.pulse_Polarity = str2double(app.EmitPolarity.Value);

                %%        接收孔径
                rev_aper.max_Aper = str2double(app.MaxRevAper.Value);
                rev_aper.min_Aper = str2double(app.MinRevAper.Value);
                rev_aper.fn_value = str2double(strsplit(app.RevAperFnum.Value));
                rev_aper.fn_depth = str2double(strsplit(app.RevAperDepth.Value));
                rev_aper_win = app.RevApodWin.Value;
                if(strcmp(rev_aper_win,'hamming'))
                    rev_aper.js_win = hamming(256);
                elseif(strcmp(rev_aper_win,'hanning'))
                    rev_aper.js_win = hanning(256);
                elseif(strcmp(rev_aper_win,'gaussian'))
                    rev_aper.js_win = gausswin(256,1.2);
                else
                    rev_aper.js_win = rectwin(256);
                end
                app.AcqConfig.Rx.aperture = rev_aper;
                %%
                app.startpt = floor(probe.image_start/(app.sos/app.AcqConfig.Rx.fs/2));
                if(app.startpt<1)
                    app.startpt =1;
                end
                sampt = round(imagedepth/(app.sos/app.AcqConfig.Rx.fs/2));
                app.endpt = app.startpt+sampt-1;
                if( app.endpt>app.Revpt)
                    app.startpt = 1;
                    app.endpt = sampt;
                    if(app.endpt>app.Revpt)
                        app.endpt = app.Revpt;
                    end

                end


                %%          发射、接收
                app.AcqConfig.Tx.wavetype = app.BeamType.Value;
                if(strcmp(app.BeamType.Value,'Focus Wave'))
                    [tx_info,scanlist,cstartoffset ]= Tx_beamform(emit_line,app.AcqConfig,txwave);
                    app.AcqConfig.Tx.sequence = tx_info.sequence;
                    rx_info = Rev_Info(rev_line,cstartoffset,app.AcqConfig);
                    app.AcqConfig.Rx.sequence  = rx_info.sequence;
                    app.ScanList = scanlist;
                    app.AcqConfig.Rx.post_process_fs =  app.AcqConfig.Rx.fs;
                    % 如果prf超过上限，则自动设置为上限
                    maxprf = floor(3.0/((scanlist(1).SampleN*app.Channel*2+32)/1024/1024/1024)/2)*2;
                    if(app.capturespeed>maxprf)
                        disp("The prf you selected = " + app.capturespeed + " has exceeded the limit,the appropriate values have been set.")
                        app.capturespeed = maxprf;
                        app.CaptureSpeed.Value = num2str(app.capturespeed);
                    end
                elseif(strcmp(app.BeamType.Value,'Plane Wave'))
                    temp = str2double(strsplit(app.EmitPlaneAngle.Value));
                    pw_tx_angle = temp(~isnan(temp));
                    app.AcqConfig.Tx.Steering = pw_tx_angle;
                    revloc.start_z = str2double(app.Pw_start_z.Value);
                    % if(revloc.start_z< probe.image_start)
                    %     revloc.start_z =  app.AcqConfig.Probe.image_start ;
                    %     app.Pw_start_z.Value = num2str(revloc.start_z);
                    % end
                    app.startpt = floor( revloc.start_z /(app.sos/app.AcqConfig.Rx.fs/2));
                    if(app.startpt<1)
                        app.startpt =1;
                    end
                    sampt = round(imagedepth/(app.sos/app.AcqConfig.Rx.fs/2));
                    app.endpt = app.startpt+sampt-1;
                    if( app.endpt>app.Revpt)
                        app.startpt = 1;
                        app.endpt = sampt;
                        if(app.endpt>app.Revpt)
                            app.endpt = app.Revpt;
                        end

                    end

                    revloc.step_x = str2double(app.Pw_step_x.Value);
                    revloc.start_x =  str2double(app.Pw_start_x.Value);
                    revloc.end_x =  str2double(app.Pw_end_x.Value);
                    revloc.x_num = floor((revloc.end_x- revloc.start_x)/ revloc.step_x);

                    revloc.step_z = str2double(app.Pw_step_z.Value);
                    revloc.start_z =  str2double(app.Pw_start_z.Value);
                    revloc.end_z =  str2double(app.Pw_end_z.Value);
                    revloc.z_num = floor((revloc.end_z- revloc.start_z)/ revloc.step_z);

                    if(app.ImageRegion_PlaneWave_Auto.Value==1)

                        revloc.start_x = app.AcqConfig.Probe.element_pos.x(1);
                        revloc.end_x = app.AcqConfig.Probe.element_pos.x(end);
                        revloc.x_num = app.AcqConfig.Probe.element_num*2;
                        revloc.step_x = (revloc.end_x -revloc.start_x)/ (revloc.x_num-1);
                        app.Pw_start_x.Value = num2str(revloc.start_x);
                        app.Pw_end_x.Value = num2str(revloc.end_x);
                        app.Pw_step_x.Value = num2str(revloc.step_x);


                        revloc.step_z = app.sos/app.AcqConfig.Rx.fs/2;
                        revloc.end_z = imagedepth;
                        revloc.z_num = floor((revloc.end_z- revloc.start_z)/ revloc.step_z);


                        app.Pw_start_z_UPW.Value = num2str(revloc.start_z);
                        app.Pw_end_z_UPW.Value = num2str(revloc.end_z);
                        app.Pw_step_z_UPW.Value = num2str(revloc.step_z);

                    end

                    app.AcqConfig.Rx.post_process_fs = app.sos/revloc.step_z/2;


                    [tx_info,scanlist,rx_info]=  Tx_beamform_PlaneWave(pw_tx_angle,app.AcqConfig,txwave,revloc);
                    app.AcqConfig.Tx.sequence = tx_info.sequence;
                    app.ScanList = scanlist;
                    app.AcqConfig.Rx.sequence = rx_info.sequence;
                    %平面波都是一样的

                    startx =  revloc.start_x;
                    startz =  revloc.start_z;
                    setpx =  revloc.step_x;
                    setpz =  revloc.step_z;

                    Nx =  revloc.x_num;
                    Nz =  revloc.z_num;

                    x_axis = (0:setpx:(Nx-1) * setpx)+ startx;
                    z_axis = (0:setpz:(Nz-1) * setpz)+ startz;

                    app.AcqConfig.Rx.rev_line.x = x_axis;
                    app.AcqConfig.Rx.rev_line.z = z_axis;


                    [x_grid, z_grid] = meshgrid(x_axis,z_axis);
                    x_grid = reshape(x_grid,[Nz*Nx,1]);
                    z_grid = reshape(z_grid,[Nz*Nx,1]);
                    f_mask = zeros(Nx*Nz,probe.element_num);

                    for i = 1:probe.element_num
                        f_mask(:,i) = z_grid./abs(x_grid - probe.element_pos.x(i))/2 > app.AcqConfig.Rx.aperture.fn_value(1);
                    end

                    for i = 1:size(f_mask,1)
                        temp = f_mask(i,:);
                        loc = temp==1;
                        % if(saper< app.AcqConfig.Rx.aperture.min_Aper)
                        %      %%add
                        % end

                        temp(1,loc) =  app.AcqConfig.Rx.aperture.js_win(floor(linspace(1,256,sum(temp))));
                        f_mask(i,:) = temp;
                    end

                    app.AcqConfig.Rx.apod  = f_mask;
                    app.capturetimes = str2double(app.CaptureFrames.Value)*size(app.ScanList,2)/app.capturespeed;

                    % 如果prf超过上限，则自动设置为上限
                    maxprf = floor(3.0/((scanlist(1).SampleN*app.Channel*2+32)/1024/1024/1024)/2)*2;
                    if(app.capturespeed>maxprf)
                        disp("The prf you selected = " + app.capturespeed + " has exceeded the limit,the appropriate values have been set")
                        app.capturespeed = maxprf;
                        app.CaptureSpeed_UPW.Value = num2str(app.capturespeed);
                    end

                end


            elseif(strcmp(app.modetype,'UltraPW'))
                app.EmitBeamType = 'Plane Wave';
                app.SysRate = str2double(app.SampleRate_UPW.Value);
                app.AcqConfig.Tx.fs = app.SysRate;
                app.AcqConfig.Tx.sos = str2double(app.SoundSpeed_UPW.Value);
                app.sos = app.AcqConfig.Tx.sos ;
                app.Channel = str2double(app.MachineChannel_UPW.Value);
                app.capturetimes = str2double(app.CaptureTimes_UPW.Value);
                if(app.Channel==128)
                    app.PortType = 1;
                else
                    app.PortType = 3;
                end
                app.AcqConfig.Tx.channel = app.Channel;
                focus_depth = -1;
                app.AcqConfig.Tx.focus_depth =  focus_depth;
                app.capturespeed = str2double(app.CaptureSpeed_UPW.Value);
                app.framerate_upw =  str2double(app.FrameRate_UPW.Value);
                % if(app.SaveADC_UPW.Value==1)
                %     % if(isempty( app.upw_folderPath))
                %     folderPath = uigetdir('', 'Select Folder to Save');
                %     if folderPath ~= 0
                %         currentTime = datetime('now');
                %         currentTime.Format = 'yyyyMMddHHmmss';
                %         timeStr = char(currentTime);  % 将 datetime 转换为 char 类型的字符串
                %         fullPath = fullfile(folderPath, strcat(timeStr,'\ADC'));
                %         if ~exist(fullPath,'dir')
                %             mkdir(fullPath);
                %         end
                %         app.upw_folderPath = fullPath;
                %     end
                %     % end
                %
                % else
                %     app.upw_folderPath = [];
                % end
                %%          配置探头参数
                probe_name = app.Probe_UPW.Value;
                probe =  Probe_para(probe_name);
                app.AcqConfig.Probe = probe;
                %%          采样点数
                imagedepth = str2double(app.ImageDepth_UPW.Value);
                [Rx,app.Revpt,app.Revdepth ]= SampleInfo(app.AcqConfig,imagedepth);
                app.AcqConfig.Rx = Rx;
                app.AcqConfig.Rx.Revpt = app.Revpt;
                app.AcqConfig.Rx.Revdepth = app.Revdepth;
                app.AcqConfig.Rx.WaveType = app.BeamType.Value;

                %%          发射波形
                txwave.voltage = str2double(app.EmitVol_UPW.Value);
                txwave.pulse_num = str2double(app.EmitPulseNum_UPW.Value);
                txwave.pulse_duration = str2double(app.EmitDuration_UPW.Value);
                txwave.pulse_frequency = str2double(app.EmitPulseFre_UPW.Value);
                txwave.pulse_Polarity = str2double(app.EmitPolarity_UPW.Value);


                %%        接收孔径
                rev_aper.max_Aper = str2double(app.MaxRevAper_UPW.Value);
                rev_aper.min_Aper = str2double(app.MinRevAper_UPW.Value);
                rev_aper.fn_value = str2double(app.RevAperFnum_UPW.Value);
                rev_aper.fn_depth = str2double(strsplit(app.RevAperDepth_UPW.Value));
                rev_aper_win = app.RevApodWin_UPW.Value;
                if(strcmp(rev_aper_win,'hamming'))
                    rev_aper.js_win = hamming(256);
                elseif(strcmp(rev_aper_win,'hanning'))
                    rev_aper.js_win = hanning(256);
                elseif(strcmp(rev_aper_win,'gaussian'))
                    rev_aper.js_win = gausswin(256,1.2);
                else
                    rev_aper.js_win = rectwin(256);
                end
                app.AcqConfig.Rx.aperture = rev_aper;


                %%          发射、接收

                if(strcmp(app.EmitBeamType,'Plane Wave'))

                    temp = str2double(strsplit(app.EmitPlaneAngle_UPW.Value));
                    pw_tx_angle = temp(~isnan(temp));
                    
                    app.AcqConfig.Tx.Steering = pw_tx_angle;
                    revloc.start_z = str2double(app.Pw_start_z_UPW.Value);
                    % if(revloc.start_z< probe.image_start)
                    % revloc.start_z =  app.AcqConfig.Probe.image_start ;
                    % app.Pw_start_z_UPW.Value = num2str(revloc.start_z);
                    % end

                    %%
                    app.startpt = floor(revloc.start_z/(app.sos/app.AcqConfig.Rx.fs/2));
                    if(app.startpt<1)
                        app.startpt =1;
                    end
                    sampt = round(imagedepth/(app.sos/app.AcqConfig.Rx.fs/2));
                    app.endpt = app.startpt+sampt-1;
                    if( app.endpt>app.Revpt)
                        app.startpt = 1;
                        app.endpt = sampt;
                        if(app.endpt>app.Revpt)
                            app.endpt = app.Revpt;
                        end

                    end


                    revloc.step_x = str2double(app.Pw_step_x_UPW.Value);
                    revloc.start_x =  str2double(app.Pw_start_x_UPW.Value);
                    revloc.end_x =  str2double(app.Pw_end_x_UPW.Value);
                    revloc.x_num = floor((revloc.end_x- revloc.start_x)/ revloc.step_x);

                    revloc.step_z = str2double(app.Pw_step_z_UPW.Value);
                    revloc.start_z =  str2double(app.Pw_start_z_UPW.Value);
                    revloc.end_z =  str2double(app.Pw_end_z_UPW.Value);
                    revloc.z_num = floor((revloc.end_z- revloc.start_z)/ revloc.step_z);

                    if(app.ImageRegion_UPW_Auto.Value==1)

                        revloc.start_x = app.AcqConfig.Probe.element_pos.x(1);
                        revloc.end_x = app.AcqConfig.Probe.element_pos.x(end);
                        revloc.x_num = app.AcqConfig.Probe.element_num*2;
                        revloc.step_x = (revloc.end_x -revloc.start_x)/ (revloc.x_num-1);
                        app.Pw_start_x_UPW.Value = num2str(revloc.start_x);
                        app.Pw_end_x_UPW.Value = num2str(revloc.end_x);
                        app.Pw_step_x_UPW.Value = num2str(revloc.step_x);


                        revloc.step_z = app.sos/app.AcqConfig.Rx.fs/2;
                        revloc.end_z = imagedepth;
                        revloc.z_num = floor((revloc.end_z- revloc.start_z)/ revloc.step_z);


                        app.Pw_start_z_UPW.Value = num2str(revloc.start_z);
                        app.Pw_end_z_UPW.Value = num2str(revloc.end_z);
                        app.Pw_step_z_UPW.Value = num2str(revloc.step_z);

                    end

                    app.AcqConfig.Rx.post_process_fs = app.sos/revloc.step_z/2;


                    [tx_info,scanlist,rx_info]=  Tx_beamform_PlaneWave(pw_tx_angle,app.AcqConfig,txwave,revloc);
                    app.AcqConfig.Tx.sequence = tx_info.sequence;
                    app.ScanList = scanlist;
                    app.AcqConfig.Rx.sequence = rx_info.sequence;
                    %平面波都是一样的

                    startx =  revloc.start_x;
                    startz =  revloc.start_z;
                    setpx =  revloc.step_x;
                    setpz =  revloc.step_z;

                    Nx =  revloc.x_num;
                    Nz =  revloc.z_num;

                    x_axis = (0:setpx:(Nx-1) * setpx)+ startx;
                    z_axis = (0:setpz:(Nz-1) * setpz)+ startz;

                    app.AcqConfig.Rx.rev_line.x = x_axis;
                    app.AcqConfig.Rx.rev_line.z = z_axis;


                    [x_grid, z_grid] = meshgrid(x_axis,z_axis);
                    x_grid = reshape(x_grid,[Nz*Nx,1]);
                    z_grid = reshape(z_grid,[Nz*Nx,1]);
                    f_mask = zeros(Nx*Nz,probe.element_num);

                    for i = 1:probe.element_num
                        f_mask(:,i) = z_grid./abs(x_grid - probe.element_pos.x(i))/2 > app.AcqConfig.Rx.aperture.fn_value(1);
                    end

                    for i = 1:size(f_mask,1)
                        temp = f_mask(i,:);
                        loc = temp==1;
                        % if(saper< app.AcqConfig.Rx.aperture.min_Aper)
                        %      %%add
                        % end

                        temp(1,loc) =  app.AcqConfig.Rx.aperture.js_win(floor(linspace(1,256,sum(temp))));
                        f_mask(i,:) = temp;
                    end

                    app.AcqConfig.Rx.apod  = f_mask;

                    % 如果prf超过上限，则自动设置为上限
                    maxprf = floor(3.0/((scanlist(1).SampleN*app.Channel*2+32)/1024/1024/1024)/2)*2;
                    if(app.capturespeed>maxprf)
                        disp("The prf you selected = " + app.capturespeed + " has exceeded the limit,the appropriate values have been set")
                        app.capturespeed = maxprf;
                        app.CaptureSpeed_UPW.Value = num2str(app.capturespeed);
                    end


                end


            else

            end

            %%          后处理参数
            PostProcssPara(app);
        end

        function  frequency_analysis(app)
            if(app.ImageCheck.Value==1)

                if(app.IsBImg)
                    cla(app.FreSpectrumImage,"reset");
                    app.FreSpectrumChannel.Visible = 'off';
                    app.FreSpectrumCLabel.Visible = 'off';
                    app.FreSpectrumImage.XTick = [];
                    app.FreSpectrumImage.YTick = [];
                    return;
                end
                value = app.FreSpectrumSwitch.Value;

                if(value==1)
                    if(app.IsRunning==false&&app.ImageCheck.Value)
                        frame = app.ImgaeSlider.Value+1;

                        if(app.IsAdcImg)
                            app.FreSpectrumChannel.Visible = 'on';
                            app.FreSpectrumCLabel.Visible = 'on';
                            if(~isempty(app.AdcData))
                                cla(app.FreSpectrumImage,"reset");
                                temp = app.AdcData{frame};
                                MaxPt = size(temp,1);
                                MaxLine= size(temp,2);
                                [DRange,LRange,CRange] = frequencey_range(app,MaxPt,MaxLine);
                                data = temp(DRange,CRange,LRange);

                                frespec_all = 0;
                                LineL = length(LRange);
                                for i = 1:LineL

                                    [frespec,line_fre] = fpectral_analyse(data(:,:,i),app.AcqConfig.Rx.fs);
                                    frespec_all = frespec_all+ frespec;
                                end
                                line_fre =line_fre/1e6;
                                fre = smooth(mean(frespec_all/LineL,2),32);
                                plot(app.FreSpectrumImage,line_fre, fre);
                                app.FreSpectrumImage.XTick =  linspace(line_fre(1),line_fre(end),5);
                                app.FreSpectrumImage.YTick =  linspace(min(fre),max(fre),5);
                                xlabel(app.FreSpectrumImage,'Frequency (MHz)');
                                drawnow;
                            end


                        elseif(app.IsBImg)
                            app.FreSpectrumChannel.Visible = 'off';
                            app.FreSpectrumCLabel.Visible = 'off';

                        elseif(app.IsRfImg)
                            app.FreSpectrumChannel.Visible = 'off';
                            app.FreSpectrumCLabel.Visible = 'off';
                            if(~isempty(app.RfData))
                                cla(app.FreSpectrumImage,"reset");
                                temp = app.RfData{frame};
                                MaxPt = size(temp,1);
                                MaxLine= size(temp,2);
                                [DRange,LRange] = frequencey_range(app,MaxPt,MaxLine);
                                data = temp(DRange,LRange);
                                [frespec_all,line_fre] = fpectral_analyse(data,app.AcqConfig.Rx.fs);
                                line_fre =line_fre/1e6;
                                fre = smooth(mean(frespec_all,2),32);
                                plot(app.FreSpectrumImage,line_fre, fre);
                                app.FreSpectrumImage.XTick =  linspace(line_fre(1),line_fre(end),5);
                                app.FreSpectrumImage.YTick =  linspace(min(fre),max(fre),5);
                                xlabel(app.FreSpectrumImage,'Frequency (MHz)');
                                drawnow;
                            end

                        end
                    end
                end


            else
                if(strcmp(app.ImageType.Value,'ADC'))
                    app.FreSpectrumChannel.Visible = 'on';
                    app.FreSpectrumCLabel.Visible = 'on';
                else
                    app.FreSpectrumChannel.Visible = 'off';
                    app.FreSpectrumCLabel.Visible = 'off';
                end
                app.FreSpectrumSwitch.Value = 0;

                uialert(app.SignalProcessUIFigure,'Please select the data type before spectrum analysis', 'Warning', ...
                    'Icon', 'warning', 'Modal', true);

            end
        end

        function   [DRange,LRange,CRange] = frequencey_range(app,MaxPt,MaxLine)
            [DRange,Dflag ]= extractvalue(app,app.FreSpectrumDRange.Value);
            DRange(DRange>MaxPt) = [];
            DRange(DRange<1) = [];
            [LRange,Lflag  ] = extractvalue(app,app.FreSpectrumLRange.Value);
            LRange(LRange>MaxLine) = [];
            LRange(LRange<1) = [];
            [CRange,Cflag  ] = extractvalue(app,app.FreSpectrumChannel.Value);
            CRange(CRange>app.Channel) = [];
            CRange(CRange<1) = [];

            if(~isempty(DRange))
                PtL = length(DRange);
                if(Dflag)

                    if(PtL>=2)
                        app.FreSpectrumDRange.Value = sprintf('%d:%d',DRange(1),DRange(end));
                    elseif(PtL==1)
                        app.FreSpectrumDRange.Value = addchars(app,DRange);
                        uialert(app.SignalProcessUIFigure,'Please enter the appropriate spectrum depth range', 'Warning', ...
                            'Icon', 'warning', 'Modal', true);
                        return;
                    else
                        uialert(app.SignalProcessUIFigure,'Please enter the appropriate spectrum depth range', 'Warning', ...
                            'Icon', 'warning', 'Modal', true);
                        return;
                    end
                else
                    if(PtL>=2)
                        app.FreSpectrumDRange.Value = addchars(app,DRange);
                    else


                        app.FreSpectrumDRange.Value = addchars(app,DRange);
                        uialert(app.SignalProcessUIFigure,'Please enter the appropriate spectrum depth range', 'Warning', ...
                            'Icon', 'warning', 'Modal', true);
                        return;
                    end
                end
            else
                uialert(app.SignalProcessUIFigure,'Please enter the appropriate spectrum depth range', 'Warning', ...
                    'Icon', 'warning', 'Modal', true);
                return;
            end

            if(~isempty(LRange))
                LineL = length(LRange);
                if(Lflag)

                    if(LineL>=2)
                        app.FreSpectrumLRange.Value = sprintf('%d:%d',LRange(1),LRange(end));
                    elseif(LineL==1)
                        app.FreSpectrumLRange.Value = addchars(app,LRange);
                    else
                        uialert(app.SignalProcessUIFigure,'Please enter the appropriate spectrum line range', 'Warning', ...
                            'Icon', 'warning', 'Modal', true);
                        return;
                    end
                else

                    app.FreSpectrumLRange.Value = addchars(app,LRange);
                end
            else
                uialert(app.SignalProcessUIFigure,'Please enter the appropriate spectrum line range', 'Warning', ...
                    'Icon', 'warning', 'Modal', true);
                return;
            end


            if(~isempty(CRange))
                ChL = length(CRange);
                if(Cflag)

                    if(ChL>=2)
                        app.FreSpectrumChannel.Value = sprintf('%d:%d',CRange(1),CRange(end));
                    elseif(ChL==1)
                        app.FreSpectrumChannel.Value = addchars(app,CRange);
                    else
                        uialert(app.SignalProcessUIFigure,'Please enter the appropriate spectrum channel range', 'Warning', ...
                            'Icon', 'warning', 'Modal', true);
                        return;
                    end
                else

                    app.FreSpectrumChannel.Value = addchars(app,CRange);
                end
            else
                uialert(app.SignalProcessUIFigure,'Please enter the appropriate spectrum channel range', 'Warning', ...
                    'Icon', 'warning', 'Modal', true);
                return;
            end

        end

        function   PostProcssPara(app)
            %%          配置后处理参数
            if(strcmp(app.modetype,'B'))
                app.postprocess.demo_depth = str2double(strsplit(app.DemodulationDepth.Value));
                app.postprocess.demo_value = str2double(strsplit(app.DemodulationValue.Value));

                app.postprocess.dgain_global_value = str2double(app.GlobalDigitalGain.Value);
                app.postprocess.dgain_depth = str2double(strsplit(app.DigitalGainDepth.Value));
                app.postprocess.dgain_value = str2double(strsplit(app.DigitalGainValue.Value));
                app.postprocess.slider0 = app.SliderGain0.Value;
                app.postprocess.slider1 = app.SliderGain1.Value;
                app.postprocess.slider2 = app.SliderGain2.Value;
                app.postprocess.slider3 = app.SliderGain3.Value;
                app.postprocess.slider4 = app.SliderGain4.Value;
                app.postprocess.slider5 = app.SliderGain5.Value;
                app.postprocess.knobgain = app.KnobGain.Value;
                app.postprocess.dynamic_range = str2double(app.DynamicRange.Value);
                app.postprocess.spatial_smooth_level = str2double(app.SpatialSmoothLevel.Value);
                app.postprocess.filtercoef = str2double(app.FilterCoef.Value);
                app.postprocess.filterorder = str2double(app.FilterOrder.Value);
                app.postprocess.filtertype = app.FilterType.Value;
                app.postprocess.smooth_level = str2double(app.SpatialSmoothLevel.Value);
                app.postprocess.grayMapidx = str2double(app.GrayMapIdx.Value);
                app.postprocess.timtMapidx = str2double(app.TimtMapIdx.Value);
                app.postprocess.dsc_height = app.dsc_height;
                app.postprocess.dsc_width = app.dsc_width;
            elseif(strcmp(app.modetype,'UltraPW'))

                app.postprocess.demo_depth = str2double(strsplit(app.DemodulationDepth_UPW.Value));
                app.postprocess.demo_value = str2double(strsplit(app.DemodulationValue_UPW.Value));

                app.postprocess.dynamic_range = str2double(app.DynamicRange_UPW.Value);
                app.postprocess.filtercoef = str2double(app.FilterCoef_UPW.Value);
                app.postprocess.filterorder = str2double(app.FilterOrder_UPW.Value);
                app.postprocess.filtertype = app.FilterType_UPW.Value;
                app.postprocess.svd_l = str2double(app.Svd_H.Value);
                app.postprocess.svd_h = str2double(app.Svd_L.Value);
                app.postprocess.dynamic_range = str2double(app.DynamicRange_UPW.Value);
            else
            end


        end

        function  capture_show_img(app)
            app.AdcData = [];
            app.RfData =  [];
            app.BData = [];
            app.IsRunning = true;
            app.PostProcessRun.Visible = 'off';
            app.FreSpectrumSwitch.Value = 0;
            app.FreSpectrumSwitch.Enable = 'off';
            app.FreSpectrumCLabel.Enable = 'off';
            app.FreSpectrumChannel.Enable = 'off';
            app.FreSpectrumDRange.Enable = 'off';
            app.FreSpectrumLRange.Enable = 'off';
            app.ImgaeSlider.Value = 0;
            app.ImageLoc.Value = '0';
            update_paras(app);
            %%     下发配置
            libpath = fullfile(app.currentPath,'lib/DAQ_SDK_C.dll');
            app.dev_manager = DAQ( app.AcqConfig,app.ScanList,libpath,app.Revpt,app.capturespeed);
            % 将修改的属性发送到DAQ节点
            disp("set DAQ ...");
            res = app.dev_manager.set_DAQ(app.PortType);
            if(res==0)
                disp("set DAQ ok");
            else
                uialert(app.SignalProcessUIFigure,'If the DAQ connection fails, please run the program again, if it still fails, please turn off all devices and plug the network cable again, and restart the device', 'Failed', ...
                    'Icon', 'error', 'Modal', true);
                System.GC.Collect();
                app.dev_manager.stop_sample;
                app.dev_manager.close_DAQ;
                return;
            end
            pause(2);
            %%     数据采集
            disp("start sample ...");
            res =  app.dev_manager.start_sample;
            if res
                disp("start sample ok");
            else
                uialert(app.SignalProcessUIFigure,'Please shut down and restart the device and plug in the network cable again', 'Failed', ...
                    'Icon', 'error', 'Modal', true);
                if(max( ismember( fieldnames( app ) ,"dev_manager")))
                    System.GC.Collect();
                    app.dev_manager.stop_sample;
                    app.dev_manager.close_DAQ;
                end
                return;
            end
            disp("apply for frame_buf .NET memory ...");
            frame_buf = NET.createArray("System.Int16",  app.Revpt* app.AcqConfig.Probe.element_num);
            disp("apply for frame_buf .NET memory ok");
            %             load sample_data.mat
            app.frame_nums = 1;
            tx_num = 1;
            while(app.IsRunning )
                recv_data = zeros(app.Revpt,app.AcqConfig.Tx.channel,numel(app.AcqConfig.Tx.sequence));
                if(strcmp(app.BeamType.Value,'Focus Wave'))
                    rf = zeros(app.AcqConfig.Rx.sample_num,numel(app.AcqConfig.Tx.sequence));
                    app.bimg_no_dsc = zeros(app.AcqConfig.Rx.sample_num,numel(app.AcqConfig.Tx.sequence),3);
                    if(app.DscFlag.Value==1)
                        app.bimg_dsc = zeros(app.dsc_height,app.dsc_width,3);
                    else
                        app.bimg_dsc = zeros(app.AcqConfig.Rx.sample_num,numel(app.AcqConfig.Tx.sequence),3);
                    end
                else
                    rf = zeros(app.AcqConfig.Rx.sequence{1, tx_num}.z_num,app.AcqConfig.Rx.sequence{1, tx_num}.x_num,numel(app.AcqConfig.Tx.sequence));
                    app.bimg_no_dsc = zeros(app.AcqConfig.Rx.sequence{1, tx_num}.z_num,app.AcqConfig.Rx.sequence{1, tx_num}.x_num,3);
                    if(app.DscFlag.Value==1)
                        app.bimg_dsc = zeros(app.dsc_height,app.dsc_width,3);
                    else
                        app.bimg_dsc = zeros(app.AcqConfig.Rx.sequence{1, tx_num}.z_num,app.AcqConfig.Rx.sequence{1, tx_num}.x_num,3);
                    end


                end

                cla(app.ImageShow, 'reset');
                cla(app.FreSpectrumImage,'reset');
                app.FreSpectrumImage.XTick = [];
                app.FreSpectrumImage.YTick = [];
                app.pre_frame_id = -1;
                elapsedTime = 0;
                while tx_num <= numel(app.AcqConfig.Tx.sequence)

                    [flag, ~, ~, frame_id, sln, scan_len, frame_start, frame_end] = app.dev_manager.get_frame_data(frame_buf);
                    if(tx_num==1&&flag==0)
                        tic
                        [flag, ~, ~, frame_id] = app.dev_manager.get_frame_data(frame_buf);
                        tempTime = toc;
                        elapsedTime = elapsedTime+tempTime;
                        if(elapsedTime>10)
                            uialert(app.SignalProcessUIFigure,'If the data cannot be uploaded, please disable MATLAB and collect it again, if the data from the above operations still cannot be uploaded, please turn off the device and restart it', 'Failed', ...
                                'Icon', 'error', 'Modal', true);
                            if(max( ismember( fieldnames( app ) ,"dev_manager")))
                                System.GC.Collect();
                                app.dev_manager.stop_sample;
                                app.dev_manager.close_DAQ;
                                return;
                            end
                        end
                    end

                    if flag
                        cur_frame_id = mod(frame_id,numel(app.AcqConfig.Tx.sequence));
                        if(cur_frame_id-app.pre_frame_id==1)
                            disp("recv successfully, frame_id:" + num2str(frame_id) + "  " + ...
                                "sln:"+ num2str(sln) +  "  " + ...
                                "scan_len:"+ num2str(scan_len) +  "  " + ...
                                "frame_start:" + num2str(frame_start) +  "  " + ...
                                "frame_end:"+ num2str(frame_end));

                            app.pre_frame_id = cur_frame_id;
                        else
                            disp("recv failed frame:"+num2str(app.pre_frame_id));
                            if(max( ismember( fieldnames( app ) ,"dev_manager")))
                                System.GC.Collect();
                                app.dev_manager.stop_sample;
                                app.dev_manager.close_DAQ;
                            end
                            uialert(app.SignalProcessUIFigure,'Receive frame failed', 'Failed', ...
                                'Icon', 'error', 'Modal', true);
                            return;

                        end

                        recv_data(:,:,tx_num) = vxreshape(app.AcqConfig,app.Revpt,single(frame_buf));
                        %                         recv_data(:,:,tx_num) =sample_data(:,:,tx_num);
                        if(strcmp(app.BeamType.Value,'Focus Wave'))

                            temp = DAS(recv_data(:,:,tx_num),app.AcqConfig.Rx.sequence{tx_num},app.AcqConfig.Rx.aperture,app.Revpt, ...
                                app.AcqConfig.Rx.fs,app.sos,app.AcqConfig.Tx.channel,app.AcqConfig.Probe);
                            rf(:,tx_num) =  temp(app.startpt:app.endpt);
                            % app.bimg_no_dsc(:,tx_num,:) = BProcess(rf(:,tx_num), app.AcqConfig, app.postprocess,0);
                            app.bimg_no_dsc(:,tx_num,:) = BProcess(rf(:,tx_num), app.AcqConfig.Rx.fs,app.AcqConfig.Tx.sos,app.AcqConfig.Rx.rev_line, app.AcqConfig.Probe,app.postprocess,0);


                        else

                            temp = DAS_PlaneWave(recv_data(:,:,tx_num),app.AcqConfig.Rx.rev_line.x,app.AcqConfig.Rx.rev_line.z,app.AcqConfig.Rx.apod,  ...
                                app.AcqConfig.Rx.fs,app.sos,app.AcqConfig.Probe, app.AcqConfig.Rx.sequence{1,tx_num}.steer);

                            rf(:,:,tx_num) =  temp;
                            app.bimg_no_dsc = BProcess(temp, app.AcqConfig.Rx.fs,app.AcqConfig.Tx.sos,app.AcqConfig.Rx.rev_line, app.AcqConfig.Probe,app.postprocess,0);

                        end

                        if(app.ImageCheck.Value)
                            if(app.IsAdcImg)
                                temp = recv_data(:,:,tx_num)';
                                maxtemp =  abs(max(temp(:)))+1000;
                                imagesc(app.ImageShow,temp,[-maxtemp,maxtemp]);
                                axis(app.ImageShow, 'tight');
                                colormap(app.ImageShow, app.AdcMap);
                                pause(0.0001);
                                drawnow;
                            elseif(app.IsRfImg)


                                if(strcmp(app.BeamType.Value,'Focus Wave'))
                                    scale=max(max(rf));
                                    v = rf/scale;
                                    plot(app.ImageShow,v(:,tx_num)+tx_num, 1:app.AcqConfig.Rx.sample_num);
                                    axis(app.ImageShow, 'tight');
                                    set(app.ImageShow, 'YDir', 'reverse');
                                    drawnow;
                                    pause(0.0001);
                                else


                                    scale=max(max(rf(:,:,tx_num)));
                                    v = rf(:,:,tx_num)/scale;

                                    plot(app.ImageShow,v+(1:size(rf,2)), 1:app.AcqConfig.Rx.sample_num);

                                    hold(app.ImageShow, 'on');
                                    axis(app.ImageShow, 'tight');
                                    set(app.ImageShow, 'YDir', 'reverse');
                                    drawnow;
                                    pause(0.001);

                                    %                                     end

                                end

                            elseif(app.IsBImg)
                                if(strcmp(app.BeamType,'Focus Wave'))

                                    x = app.AcqConfig.Rx.rev_line.x;
                                    z = linspace(0, app.AcqConfig.Rx.ImageDepth,app.AcqConfig.Rx.sample_num);
                                    image( app.ImageShow,x,z,uint8(app.bimg_no_dsc));

                                else
                                    x = app.AcqConfig.Rx.rev_line.x;
                                    z = app.AcqConfig.Rx.rev_line.z;
                                    image( app.ImageShow,x,z,uint8(app.bimg_no_dsc));

                                end

                                drawnow;
                                pause(0.0001);
                            else

                            end
                        end
                        app.ImgaeSlider.Value = app.frame_nums-1;
                        app.ImageLoc.Value = num2str(app.frame_nums-1);
                        tx_num = tx_num + 1;

                    else
                    end


                end
                fprintf("...................................Complete %d frame of data acquisition...................................\n",app.frame_nums);

                tx_num = 1;
                if(strcmp(app.BeamType,'Focus Wave'))
                    app.bimg_dsc = BProcess(rf, app.AcqConfig.Rx.fs,app.AcqConfig.Tx.sos,app.AcqConfig.Rx.rev_line, app.AcqConfig.Probe,app.postprocess,1);

                else
                    app.bimg_dsc = BProcess(sum(rf,3), app.AcqConfig.Rx.fs,app.AcqConfig.Tx.sos,app.AcqConfig.Rx.rev_line, app.AcqConfig.Probe,app.postprocess,1);
                    app.bimg_no_dsc = BProcess(sum(rf,3), app.AcqConfig.Rx.fs,app.AcqConfig.Tx.sos,app.AcqConfig.Rx.rev_line, app.AcqConfig.Probe,app.postprocess,0);
                    rf = sum(rf,3);

                end
                if(app.ImageCheck.Value)
                    if(strcmp(app.ImageType.Value,'ADC'))
                        cla(app.ImageShow,"reset");
                        temp = permute(recv_data,[2,1,3]);
                        for i = 1:size(temp,3)
                            RawFrames(:,(i-1)*size(temp,2)+1:i*size(temp,2)) = temp(:,:,i);
                        end

                        maxtemp =  abs(max(RawFrames(:)))+1000;
                        imagesc(app.ImageShow,RawFrames,[-maxtemp,maxtemp]);

                        axis(app.ImageShow, 'tight');
                        colormap(app.ImageShow, app.AdcMap);
                        drawnow;
                    elseif(strcmp(app.ImageType.Value,'RF'))
                        cla(app.ImageShow,"reset");
                        scale=max(max(rf));
                        v = rf/scale;
                        for i = 1:size(rf,2)
                            plot(app.ImageShow,v(:,i)+i, 1:app.AcqConfig.Rx.sample_num);
                            hold(app.ImageShow, 'on');
                            axis(app.ImageShow, 'tight');
                            set(app.ImageShow, 'YDir', 'reverse');
                            drawnow;
                            pause(0.001);

                        end
                        hold(app.ImageShow, 'off');


                    elseif(strcmp(app.ImageType.Value,'B'))
                        if(app.DscFlag.Value==1)
                            image( app.ImageShow,uint8(app.bimg_dsc));
                            axis(app.ImageShow, 'off');
                            drawnow;
                        else
                            x = app.AcqConfig.Rx.rev_line.x;
                            z = linspace(0, app.AcqConfig.Rx.ImageDepth,app.AcqConfig.Rx.sample_num);
                            image( app.ImageShow,x,z,uint8(app.bimg_no_dsc));
                            drawnow;
                        end
                    else
                        cla(app.ImageShow,"reset");
                        app.ImageShow.XTick = [];
                        app.ImageShow.YTick = [];
                        cla(app.FreSpectrumImage,"reset");
                        app.FreSpectrumImage.XTick = [];
                        app.FreSpectrumImage.YTick = [];
                        app.IsAdcImg = false;
                        app.IsRfImg = false;
                        app.IsBImg = false;

                    end


                else
                    cla(app.ImageShow,"reset");
                    app.ImageShow.XTick = [];
                    app.ImageShow.YTick = [];
                    cla(app.FreSpectrumImage,"reset");
                    app.FreSpectrumImage.XTick = [];
                    app.FreSpectrumImage.YTick = [];
                    app.IsAdcImg = false;
                    app.IsRfImg = false;
                    app.IsBImg = false;
                end
                app.ImgaeSlider.Value = app.frame_nums-1;
                app.ImageLoc.Value = num2str(app.frame_nums-1);
                app.AdcData{app.frame_nums} = recv_data;
                app.RfData{app.frame_nums} = rf;
                if(app.DscFlag.Value==1)
                    app.BData{app.frame_nums} = app.bimg_dsc;
                else
                    app.BData{app.frame_nums} = app.bimg_no_dsc;
                end

                if(str2double(app.CaptureFrames.Value)>0)
                    if(app.frame_nums== str2double(app.CaptureFrames.Value))
                        uialert(app.SignalProcessUIFigure,'Data collection completed', 'Success', ...
                            'Icon', 'success', 'Modal', false);
                        app.IsRunning  = false;
                        if(~isempty(app.AdcData)||~isempty(app.RfData)||~isempty(app.BData))
                            app.PostProcessRun.Visible = 'on';
                            app.FreSpectrumSwitch.Enable = 'on';
                            app.FreSpectrumChannel.Enable = 'on';
                            app.FreSpectrumDRange.Enable = 'on';
                            app.FreSpectrumCLabel.Enable = 'on';
                            app.FreSpectrumLRange.Enable = 'on';
                        else
                            app.PostProcessRun.Visible = 'off';
                        end
                    end
                else
                    if(app.frame_nums==5)
                        app.frame_nums = 0;
                    end

                end
                app.frame_nums = app.frame_nums+1;

            end
            if(max( ismember( fieldnames( app ) ,"dev_manager")))
                System.GC.Collect();
                app.dev_manager.stop_sample;
                app.dev_manager.close_DAQ;
            end
            if(app.IsRunning==false)
                if(~isempty(app.AdcData)||~isempty(app.RfData)||~isempty(app.BData))
                    app.PostProcessRun.Visible = 'on';
                    app.FreSpectrumSwitch.Enable = 'on';
                    app.FreSpectrumCLabel.Enable = 'on';
                    app.FreSpectrumChannel.Enable = 'on';
                    app.FreSpectrumDRange.Enable = 'on';
                    app.FreSpectrumLRange.Enable = 'on';
                    AdcDataSize = length(app.AdcData);
                    if(AdcDataSize<=1)
                        app.CaptureFrames.Value = '1';
                        app.ImgaeSlider.Limits = [0,1];
                        app.ImgaeSlider.MajorTicks = [0 1];
                        app.ImgaeSlider.Value = 0;
                        app.ImageLoc.Value = '0';
                    else

                        app.ImgaeSlider.Limits = [0,AdcDataSize-1];
                        app.ImgaeSlider.MajorTicks = [0 AdcDataSize-1];
                        app.ImgaeSlider.Value = AdcDataSize-1;
                        app.ImageLoc.Value =  num2str(AdcDataSize-1);
                    end
                else
                    app.PostProcessRun.Visible = 'off';
                    app.CaptureFrames.Value = '0';
                    app.ImageLoc.Value = '0';
                    app.ImgaeSlider.Value = 0;
                end

            end
        end
        function  capture_data(app)
            app.AdcData = [];
            app.RfData =  [];
            app.BData = [];
            app.IsRunning = true;
            app.PostProcessRun.Visible = 'off';
            app.SaveB.Enable = 'off';
            app.SaveRF.Enable = 'off';
            app.FreSpectrumSwitch.Enable = 'off';
            app.FreSpectrumChannel.Enable = 'off';
            app.FreSpectrumDRange.Enable = 'off';
            app.FreSpectrumLRange.Enable = 'off';
            app.FreSpectrumCLabel.Enable = 'off';
            app.FreSpectrumSwitch.Enable = 'off';

            cla(app.ImageShow,"reset");
            app.ImageShow.XTick = [];
            app.ImageShow.YTick = [];
            cla(app.FreSpectrumImage,"reset");
            app.FreSpectrumImage.XTick = [];
            app.FreSpectrumImage.YTick = [];
            app.IsAdcImg = false;
            app.IsRfImg = false;
            app.IsBImg = false;
            update_paras(app);
            app.ImgaeSlider.Value = 0;
            app.ImageLoc.Value = '0';
            folderPath = uigetdir('', 'Select Folder to Save');
            if folderPath ~= 0
                currentTime = datetime('now');
                currentTime.Format = 'yyyyMMddHHmmss';
                timeStr = char(currentTime);  % 将 datetime 转换为 char 类型的字符串
                fullPath = fullfile(folderPath, timeStr);
                if ~exist(fullPath,'dir')
                    mkdir(fullPath);
                end

                app.frame_nums= str2double(app.CaptureFrames.Value);
                [app.NumsPerFile] = DAQ_Acquisition(app.AcqConfig,app.ScanList,app.capturespeed,app.frame_nums,fullPath );
                uialert(app.SignalProcessUIFigure, ['ADC File saved to: ', fullPath], 'Save Success', ...
                    'Icon', 'success', 'Modal', true);
                % save([fullPath,filesep,'DAQ_Acquisition_Config.mat'],'NumsPerFile','-v7.3');
                filename_para = strcat(fullPath, '\Param.txt');
                fileID = fopen(filename_para,'w');
                % //fprintf(fileID, 'wavetype: %s\n',app.AcqConfig.Tx.wavetype);
                fprintf(fileID, 'frames: %d\n',app.frame_nums);
                fprintf(fileID, 'prf: %d\n',app.capturespeed);
                fprintf(fileID, 'numsPerFile: %d\n',app.NumsPerFile);
                fprintf(fileID, 'fs: %d\n',app.AcqConfig.Rx.fs);
                fprintf(fileID, 'sampleNum: %d\n',app.AcqConfig.Rx.Revpt);
                fprintf(fileID, 'scanLine: %d\n',size(app.AcqConfig.Tx.sequence,2));
                fprintf(fileID, 'imageDepth: %f\n',app.AcqConfig.Rx.ImageDepth);
                if (strcmp(app.BeamType.Value,'Plane Wave'))
                    pw_tx_angle = str2double(strsplit(app.EmitPlaneAngle.Value));
                    fprintf(fileID, 'steer: ');
                    for i = 1:length(pw_tx_angle)
                        fprintf(fileID, '%f ',pw_tx_angle(i));
                    end
                    fprintf(fileID, '\n');
                    fprintf(fileID, 'focus: %f\n',-1);
                else
                    fprintf(fileID, 'steer: %f\n',0);
                    fprintf(fileID, 'focus: %f\n',app.AcqConfig.Tx.focus_depth);
                end
                if (strcmp(app.BeamType.Value,'Plane Wave'))
                else
                    fprintf(fileID, 'start_x step_x start_z step_z: ');
                    for i = 1:size(app.AcqConfig.Tx.sequence,2)
                        fprintf(fileID, '%f ',app.AcqConfig.Rx.sequence{1, i}.start_x,app.AcqConfig.Rx.sequence{1, i}.step_x,...
                            app.AcqConfig.Rx.sequence{1, i}.start_z,app.AcqConfig.Rx.sequence{1, i}.step_z);
                    end
                    fprintf(fileID, '\n');
                end
                fprintf(fileID, 'cstartoffset: ');
                for i = 1:size(app.AcqConfig.Tx.sequence,2)
                    fprintf(fileID, '%f ',app.AcqConfig.Rx.sequence{1, i}.cstartoffset);
                end
                fclose(fileID);

                app.ImgaeSlider.Value = app.frame_nums-1;
                app.ImageLoc.Value = num2str(app.frame_nums-1);

            else
                uialert(app.SignalProcessUIFigure, 'ADC File save was canceled', 'Cancel', ...
                    'Icon', 'warning', 'Modal', false);
            end

        end

        function capture_upw_data(app)


            cla(app.ImageShow_UPW,"reset");
            app.ImageShow_UPW.XTick = [];
            app.ImageShow_UPW.YTick = [];

            update_paras(app);

            postpara.demo_value = app.postprocess.demo_value(1) ;
            postpara.filtercoef = app.postprocess.filtercoef(1) ;
            postpara.filterorder = app.postprocess.filterorder(1) ;
            postpara.filtertype = app.postprocess.filtertype ;
            if(app.SaveADC_UPW.Value==1)
                if(app.ShowImg_UPW.Value==1)
                    [app.NumsPerFile,~,app.frame_nums] = DAQ_RealTime_PlaneWaveIQ(app.AcqConfig,postpara,app.ScanList,app.capturespeed,app.capturetimes,app.framerate_upw,app.upw_folderPath,1,1,app.ImageShow_UPW);

                else
                    [app.NumsPerFile,~,app.frame_nums] = DAQ_RealTime_PlaneWaveIQ(app.AcqConfig,postpara,app.ScanList,app.capturespeed,app.capturetimes,app.framerate_upw,app.upw_folderPath,1,0,app.ImageShow_UPW);

                end
                if(app.NumsPerFile==-1||app.frame_nums==-1)
                    uialert(app.SignalProcessUIFigure,'Please shut down and restart the device and plug in the network cable again', 'Failed', ...
                        'Icon', 'error', 'Modal', true);
                    return;
                end
                uialert(app.SignalProcessUIFigure, ['ADC File saved to: ', app.upw_folderPath], 'Save Success', ...
                    'Icon', 'success', 'Modal', true);
                filename_para = strcat(app.upw_folderPath, '\Param.txt');
                fileID = fopen(filename_para,'w');
                % //fprintf(fileID, 'wavetype: %s\n',app.AcqConfig.Tx.wavetype);
                fprintf(fileID, 'frames: %d\n',app.frame_nums);
                fprintf(fileID, 'prf: %d\n',app.capturespeed);

                fprintf(fileID, 'numsPerFile: %d\n',app.NumsPerFile);
                fprintf(fileID, 'fs: %d\n',app.AcqConfig.Rx.fs);
                fprintf(fileID, 'sampleNum: %d\n',app.AcqConfig.Rx.Revpt);
                fprintf(fileID, 'ScanLine: %d\n',length(app.AcqConfig.Rx.rev_line.x));
                fprintf(fileID, 'imageDepth: %f\n',app.AcqConfig.Rx.ImageDepth);
                pw_tx_angle = str2double(strsplit(app.EmitPlaneAngle_UPW.Value));
                fprintf(fileID, 'steer: ');
                for i = 1:length(pw_tx_angle)
                    fprintf(fileID, '%f ',pw_tx_angle(i));
                end
                fprintf(fileID, '\n');
                fprintf(fileID, 'focus: %f\n',-1);


                fprintf(fileID, 'start_x step_x start_z step_z: ');
                for i = 1:length(app.AcqConfig.Rx.rev_line.x)
                    fprintf(fileID, '%f ',app.AcqConfig.Rx.rev_line.x(i),app.AcqConfig.Rx.sequence{1, 1}.step_x,...
                        app.AcqConfig.Rx.sequence{1, 1}.start_z,app.AcqConfig.Rx.sequence{1, 1}.step_z);
                end
                fprintf(fileID, '\n');

                fprintf(fileID, 'cstartoffset: ');
                for i = 1:size(app.AcqConfig.Tx.sequence,2)
                    fprintf(fileID, '%f ',app.AcqConfig.Rx.sequence{1, i}.cstartoffset);
                end
                fclose(fileID);
                app.PostProcessRun_UPW.Visible = 'on';
                app.FusDataAnaly.Visible = 'on';

                app.BF_FrameStart_UPW.Value = num2str(1);
                app.BF_FrameEnd_UPW.Value = num2str(app.frame_nums);


                app.BF_Data_PackSize_UPW.Value = '1';
                % temp  = floor(app.frame_nums/ app.NumsPerFile);
                % if(temp==0)
                %     app.BF_FrameEnd_UPW.Value  = num2str(app.frame_nums);
                % else
                %
                %     app.BF_FrameEnd_UPW.Value = num2str(floor( app.frame_nums/ app.NumsPerFile)* app.NumsPerFile);
                %
                % end


            else
                if(app.ShowImg_UPW.Value==1)
                    [app.NumsPerFile,~,app.frame_nums] = DAQ_RealTime_PlaneWaveIQ(app.AcqConfig,postpara,app.ScanList,app.capturespeed,app.capturetimes,app.framerate_upw,app.upw_folderPath,0,1,app.ImageShow_UPW);

                    if(app.NumsPerFile==-1||app.frame_nums==-1)
                        uialert(app.SignalProcessUIFigure,'Please shut down and restart the device and plug in the network cable again', 'Failed', ...
                            'Icon', 'error', 'Modal', true);
                        return;
                    end
                end


            end


        end


        function capture_planewave_data(app)


            folderPath = uigetdir('', 'Select Folder to Save');
            if folderPath ~= 0
                currentTime = datetime('now');
                currentTime.Format = 'yyyyMMddHHmmss';
                timeStr = char(currentTime);  % 将 datetime 转换为 char 类型的字符串
                fullPath = fullfile(folderPath, strcat(timeStr,'\ADC'));
                if ~exist(fullPath,'dir')
                    mkdir(fullPath);
                end
                app.plane_wave_folderPath = fullPath;
            else
                app.plane_wave_folderPath = [];
                return;
            end

            cla(app.ImageShow,"reset");
            app.ImageShow.XTick = [];
            app.ImageShow.YTick = [];

            update_paras(app);


            postpara.demo_value = app.postprocess.demo_value(1) ;
            postpara.filtercoef = app.postprocess.filtercoef(1) ;
            postpara.filterorder = app.postprocess.filterorder(1) ;
            postpara.filtertype = app.postprocess.filtertype ;


            if(app.ImageCheck.Value==1&&app.ImageType.Value=='B')
                [app.NumsPerFile,~,app.frame_nums] = DAQ_RealTime_PlaneWaveIQ(app.AcqConfig,postpara,app.ScanList,app.capturespeed,app.capturetimes,app.framerate,app.plane_wave_folderPath,1,1,app.ImageShow);

            else
                [app.NumsPerFile,~,app.frame_nums] = DAQ_RealTime_PlaneWaveIQ(app.AcqConfig,postpara,app.ScanList,app.capturespeed,app.capturetimes,app.framerate,app.plane_wave_folderPath,1,0,app.ImageShow);

            end
            if(app.NumsPerFile==-1||app.frame_nums==-1)
                uialert(app.SignalProcessUIFigure,'Please shut down and restart the device and plug in the network cable again', 'Failed', ...
                    'Icon', 'error', 'Modal', true);
                return;
            end
            uialert(app.SignalProcessUIFigure, ['ADC File saved to: ', app.plane_wave_folderPath], 'Save Success', ...
                'Icon', 'success', 'Modal', true);
            filename_para = strcat(app.plane_wave_folderPath, '\Param.txt');
            fileID = fopen(filename_para,'w');
            fprintf(fileID, 'frames: %d\n',app.frame_nums);
            fprintf(fileID, 'prf: %d\n',app.capturespeed);
            fprintf(fileID, 'numsPerFile: %d\n',app.NumsPerFile);
            fprintf(fileID, 'fs: %d\n',app.AcqConfig.Rx.fs);
            fprintf(fileID, 'sampleNum: %d\n',app.AcqConfig.Rx.Revpt);
            fprintf(fileID, 'ScanLine: %d\n',length(app.AcqConfig.Rx.rev_line.x));
            fprintf(fileID, 'imageDepth: %f\n',app.AcqConfig.Rx.ImageDepth);
            pw_tx_angle = str2double(strsplit(app.EmitPlaneAngle.Value));
            fprintf(fileID, 'steer: ');
            for i = 1:length(pw_tx_angle)
                fprintf(fileID, '%f ',pw_tx_angle(i));
            end
            fprintf(fileID, '\n');
            fprintf(fileID, 'focus: %f\n',-1);


            fprintf(fileID, 'start_x step_x start_z step_z: ');
            for i = 1:length(app.AcqConfig.Rx.rev_line.x)
                fprintf(fileID, '%f ',app.AcqConfig.Rx.rev_line.x(i),app.AcqConfig.Rx.sequence{1, 1}.step_x,...
                    app.AcqConfig.Rx.sequence{1, 1}.start_z,app.AcqConfig.Rx.sequence{1, 1}.step_z);
            end
            fprintf(fileID, '\n');

            fprintf(fileID, 'cstartoffset: ');
            for i = 1:size(app.AcqConfig.Tx.sequence,2)
                fprintf(fileID, '%f ',app.AcqConfig.Rx.sequence{1, i}.cstartoffset);
            end
            fclose(fileID);


        end
        % 启用矩形绘制
        function enableRectangleDrawing(app)
            clearRectangle(app);
            app.RectROI = drawrectangle(app.ImageShow_UPW, ...
                'FaceAlpha',0.1, ...
                'Color','r', ...
                'InteractionsAllowed','all');

            app.RoiListeners = [
                addlistener(app.RectROI,'MovingROI',@(src,evt)app.onRectMoved(src,evt))
                addlistener(app.RectROI,'ROIMoved', @(src,evt)app.onRectMoved(src,evt))
                addlistener(app.RectROI,'ROIClicked',@(src,evt)app.onRectClicked(src,evt))
                ];
            app.onRectMoved(app.RectROI,[]);
        end
        % 清理矩形
        function clearRectangle(app)
            if ~isempty(app.RoiListeners)
                delete(app.RoiListeners); app.RoiListeners=[];
            end
            if ~isempty(app.RectROI) && isvalid(app.RectROI)
                delete(app.RectROI);

            end
            app.RectROI=[];
        end
        % 矩形移动时更新坐标
        function corners_rc = onRectMoved(app,src, evt)
            if isempty(app.RectROI) || ~isvalid(app.RectROI)
                return;
            end
            W = length(app.AcqConfig.Rx.rev_line.x);
            H =   length(app. AcqConfig.Rx.rev_line.z);
            pos = app.RectROI.Position; % [x y w h]
            x1 = max(1,pos(1));   y1 = max(1,pos(2));
            x2 = min(W,pos(1)+pos(3));  y2 = min(H,pos(2)+pos(4));
            c1 = max(1,floor(x1)); r1 = max(1,floor(y1));
            c2 = min(W,ceil(x2));  r2 = min(H,ceil(y2));
            corners_rc = [r1,c1; r1,c2; r2,c2; r2,c1];
            app.ColorROI_UPW_TextArea.Value = { ...
                sprintf('四角 [row,col]:'), ...
                sprintf('[%d,%d]',corners_rc(1,:)), ...
                sprintf('[%d,%d]',corners_rc(2,:)), ...
                sprintf('[%d,%d]',corners_rc(3,:)), ...
                sprintf('[%d,%d]',corners_rc(4,:))};
        end

    end

    methods (Access = public)

        function out = addchars(app,src)
            % 循环拼接字符，空格直接添加
            out = '';
            for i = 1:length(src)
                out = [out, num2str(src(i)), ' '];  % 每个字符后加一个空格
            end

            out(end) = [];
        end


        function [out ,flag]= extractvalue(app,inputString)
            rangePattern = '(-?\d+):(-?\d+)';
            matches = regexp(inputString, rangePattern, 'tokens');
            if ~isempty(matches)
                startValue = str2double(matches{1}{1});
                endValue = str2double(matches{1}{2});
                out = startValue:endValue;
                flag = 1;
            else
                % 处理 '400 50000 6000' 格式
                spacePattern = '(\d+)';
                matches = regexp(inputString, spacePattern, 'match');
                out = str2double(matches);
                flag = 0;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            mlappPath = fileparts(mfilename('fullpath'));

            cd(mlappPath);
            app.currentPath = pwd;
            folders = genpath(app.currentPath);
           addpath(folders);
            app.SysRate = str2double(app.SampleRate.Value);
            app.Channel = str2double(app.MachineChannel.Value);
            if(app.Channel==128)
                app.PortType = 1;
            else
                app.PortType = 3;
            end
            app.BeamType.Value  = app.BeamType.Items(2);
            if(strcmp(app.BeamType.Value,'Focus Wave'))
                app.EmitPlaneAngle.Visible = 'off';
                app.EmitFocus.Visible = 'on';
                app.EmitFocusLabel.Visible = 'on';
                app.PlaneWaveImageRegion.Visible = 'off';
                app.Emit_Aperture_Parameters.Visible = 'on';

            elseif (strcmp(app.BeamType.Value,'Plane Wave'))
                app.EmitFocus.Visible = 'off';
                app.EmitFocusLabel.Visible = 'off';
                app.EmitPlaneAngle.Visible = 'on';
                app.EmitPlaneAngleLabel.Visible = 'on';
                app.PlaneWaveImageRegion.Visible = 'on';
                app.Emit_Aperture_Parameters.Visible = 'off';
            end
            app.SaveADC.Visible = 'on';
            app.SaveRF.Visible = 'on';
            app.SaveB.Visible = 'on';
            app.LoadPara_UPW.Visible = 'off';
            app.FusDataAnaly.Visible = 'off';
            app.modetype = 'B';
            update_paras(app);


        end

        % Value changed function: EmitVol
        function EmitVolValueChanged(app, event)
            value = str2double(app.EmitVol.Value);
            if(value>95)
                uialert(app.SignalProcessUIFigure, 'The voltage cannot be greater than 100 V and it is not recommended to exceed 70 V', 'Error', ...
                    'Icon', 'error', 'Modal', true);

                app.EmitVol.Value = '95';
            elseif(value>70)
                uialert(app.SignalProcessUIFigure, 'The voltage is not recommended to exceed 70 V', 'Warning', ...
                    'Icon', 'warning', 'Modal', true);
            elseif(value<5)
                uialert(app.SignalProcessUIFigure, 'The voltage is not less than 5 V', 'Error', ...
                    'Icon', 'error', 'Modal', true);
                app.EmitVol.Value = '5';

            end
        end

        % Value changed function: EmitPulseNum
        function EmitPulseNumValueChanged(app, event)
            value = str2double(app.EmitPulseNum.Value);
            if(mod(value, 0.5)~=0)
                uialert(app.SignalProcessUIFigure, 'The period must be a multiple of 0.5', 'Error', ...
                    'Icon', 'error', 'Modal', true);

            end

        end

        % Value changed function: EmitDuration
        function EmitDurationValueChanged(app, event)

            value = str2double(app.EmitDuration.Value);
            if(value<0||value>1)
                uialert(app.SignalProcessUIFigure, 'The duty cycle input range of the transmit waveform is 0~1', 'Error', ...
                    'Icon', 'error', 'Modal', true);
            end

        end

        % Button pushed function: Run
        function RunButtonPushed(app, event)
            value = app.BeamType.Value;
            if(strcmp(value,'Focus Wave'))
                if(str2double(app.CaptureSpeed.Value)>100)

                    capture_data(app);
                elseif(str2double(app.CaptureSpeed.Value)>30)

                    capture_show_img(app);

                end

            else
                capture_planewave_data(app);

            end


        end

        % Button pushed function: Stop
        function StopButtonPushed(app, event)
            pause(1);
            app.IsRunning = false;
            % if(~isempty(app.AdcData)||~isempty(app.RfData)||~isempty(app.BData))
            %     app.PostProcessRun.Visible = 'on';
            % else
            %     app.PostProcessRun.Visible = 'off';
            % end
            if(str2double(app.CaptureSpeed.Value)>=20)
                app.PostProcessRun.Visible = 'off';
            end
        end

        % Menu selected function: SaveADC
        function SaveADCMenuSelected(app, event)

            if(str2double(app.CaptureSpeed.Value)<=100)
                folderPath = uigetdir('', 'Select Folder to Save');
                if folderPath ~= 0
                    currentTime = datetime('now');
                    currentTime.Format = 'yyyyMMddHHmmss';
                    timeStr = char(currentTime);  % 将 datetime 转换为 char 类型的字符串
                    fullPath = fullfile(folderPath, timeStr);
                    if ~exist(fullPath,'dir')
                        mkdir(fullPath);
                    end


                    Acq_Config = app.AcqConfig;
                    save([fullPath,filesep,'Acq_Config.mat'],'Acq_Config','-v7.3');
                    ADC_Data = app.AdcData;
                    save([fullPath,filesep,'ADC_Data.mat'],"ADC_Data",'-v7.3');
                    uialert(app.SignalProcessUIFigure, ['ADC File saved to: ', fullPath], 'Save Success', ...
                        'Icon', 'success', 'Modal', true);
                else
                    uialert(app.SignalProcessUIFigure, 'ADC File save was canceled', 'Cancel', ...
                        'Icon', 'warning', 'Modal', false);
                end
                % else
                %     capture_data(app);


            end

        end

        % Value changed function: ImgaeSlider
        function ImgaeSliderValueChanged(app, event)

            Value =  floor(app.ImgaeSlider.Value);
            framenum = str2double(app.CaptureFrames.Value);
            if(framenum==1)
                Value = 0;
            end
            app.ImgaeSlider.Value = Value;
            app.ImageLoc.Value = num2str(Value);

            if(app.IsRunning==false&&app.ImageCheck.Value)
                if(app.IsAdcImg)
                    if(~isempty(app.AdcData))
                        cla(app.ImageShow,"reset");
                        frame = Value+1;
                        temp = app.AdcData{frame};
                        temp = permute(temp,[2,1,3]);
                        for i = 1:size(temp,3)
                            RawFrames(:,(i-1)*size(temp,2)+1:i*size(temp,2)) = temp(:,:,i);
                        end

                        maxtemp =  abs(max(RawFrames(:)))+1000;
                        imagesc(app.ImageShow,RawFrames,[-maxtemp,maxtemp]);

                        axis(app.ImageShow, 'tight');
                        colormap(app.ImageShow, app.AdcMap);
                        drawnow;
                        frequency_analysis(app)
                    end

                elseif(app.IsRfImg)
                    if(~isempty(app.RfData))
                        cla(app.ImageShow,"reset");
                        frame = Value+1;
                        rf = app.RfData{frame};

                        scale=max(max(rf));
                        v = rf/scale;

                        plot(app.ImageShow,v+(1:size(rf,2)), 1:app.AcqConfig.Rx.sample_num);
                        axis(app.ImageShow, 'tight');
                        set(app.ImageShow, 'YDir', 'reverse');
                        frequency_analysis(app)

                    end
                elseif(app.IsBImg)
                    cla(app.ImageShow,"reset");
                    cla(app.FreSpectrumImage,'reset');
                    app.FreSpectrumImage.XTick = [];
                    app.FreSpectrumImage.YTick = [];
                    frame = Value+1;
                    if(app.DscFlag.Value==1)
                        if(~isempty( app.BData))

                            bimg = app.BData{frame};
                            image( app.ImageShow,uint8(bimg));
                            axis(app.ImageShow, 'off');
                            drawnow;
                        end
                    else
                        if(~isempty( app.BData))
                            bimg = app.BData{frame};
                            x = app.AcqConfig.Rx.rev_line.x;
                            z = linspace(0, app.AcqConfig.Rx.ImageDepth,app.AcqConfig.Rx.sample_num);
                            image( app.ImageShow,x,z,uint8(bimg));
                            drawnow;
                        end
                    end

                else
                    cla(app.ImageShow,"reset");
                    app.ImageShow.XTick = [];
                    app.ImageShow.YTick = [];
                    cla(app.FreSpectrumImage,"reset");
                    app.FreSpectrumImage.XTick = [];
                    app.FreSpectrumImage.YTick = [];
                    app.IsAdcImg = false;
                    app.IsRfImg = false;
                    app.IsBImg = false;

                end
            end


        end

        % Value changed function: CaptureFrames
        function CaptureFramesValueChanged(app, event)

            framenum = str2double(app.CaptureFrames.Value);
            if(framenum<=1)
                framenum = 2;
                app.CaptureFrames.Value = '1';
            end

            app.ImgaeSlider.Limits = [0,framenum-1];
            app.ImgaeSlider.MajorTicks = [0 framenum-1];
            app.ImgaeSlider.Value = floor((framenum-1)/2);
            app.ImageLoc.Value =  num2str(floor((framenum-1)/2));
        end

        % Value changed function: ImageLoc
        function ImageLocValueChanged(app, event)
            Value =  str2double(app.ImageLoc.Value);
            framenum = str2double(app.CaptureFrames.Value);
            if(framenum==1)
                app.ImgaeSlider.Value = 0;
                app.ImageLoc.Value = '0';
            else
                if(Value>framenum-1)
                    app.ImageLoc.Value = num2str(framenum-1);
                elseif(Value<0)
                    app.ImageLoc.Value = '0';
                end
                app.ImgaeSlider.Value = str2double(app.ImageLoc.Value);
            end
            Value =   app.ImgaeSlider.Value;

            if(app.IsRunning==false&&app.ImageCheck.Value)

                if(app.IsAdcImg)
                    if(~isempty(app.AdcData))
                        cla(app.ImageShow,"reset");
                        frame = Value+1;
                        temp = app.AdcData{frame};
                        temp = permute(temp,[2,1,3]);
                        for i = 1:size(temp,3)
                            RawFrames(:,(i-1)*size(temp,2)+1:i*size(temp,2)) = temp(:,:,i);
                        end

                        maxtemp =  abs(max(RawFrames(:)))+1000;
                        imagesc(app.ImageShow,RawFrames,[-maxtemp,maxtemp]);
                        axis(app.ImageShow, 'tight');
                        colormap(app.ImageShow, app.AdcMap);
                        drawnow;
                        frequency_analysis(app)

                    end

                elseif(app.IsRfImg)
                    if(~isempty(app.RfData))
                        cla(app.ImageShow,"reset");
                        cla(app.FreSpectrumImage,'reset');
                        app.FreSpectrumImage.XTick = [];
                        app.FreSpectrumImage.YTick = [];
                        frame = Value+1;
                        rf = app.RfData{frame};
                        scale=max(max(rf));
                        v = rf/scale;
                        plot(app.ImageShow,v+(1:size(rf,2)), 1:app.AcqConfig.Rx.sample_num);
                        axis(app.ImageShow, 'tight');
                        set(app.ImageShow, 'YDir', 'reverse');
                        frequency_analysis(app)
                    end
                elseif(app.IsBImg)
                    frame = Value+1;
                    if(app.DscFlag.Value==1)

                        if(~isempty( app.BData))
                            cla(app.ImageShow,"reset");
                            bimg = app.BData{frame};
                            image( app.ImageShow,uint8(bimg));
                            axis(app.ImageShow, 'off');
                            drawnow;
                        end
                    else
                        if(~isempty( app.BData))
                            cla(app.ImageShow,"reset");
                            bimg = app.BData{frame};
                            x = app.AcqConfig.Rx.rev_line.x;
                            z = linspace(0, app.AcqConfig.Rx.ImageDepth,app.AcqConfig.Rx.sample_num);
                            image( app.ImageShow,x,z,uint8(bimg));
                            drawnow;
                        end
                    end
                else
                    cla(app.ImageShow,"reset");
                    app.ImageShow.XTick = [];
                    app.ImageShow.YTick = [];
                    cla(app.FreSpectrumImage,"reset");
                    app.FreSpectrumImage.XTick = [];
                    app.FreSpectrumImage.YTick = [];
                    app.IsAdcImg = false;
                    app.IsRfImg = false;
                    app.IsBImg = false;

                end

            end

        end

        % Value changed function: ImageType
        function ImageTypeValueChanged(app, event)
            if(strcmp(app.BeamType.Value,'Focus Wave'))
                value = app.ImageType.Value;
                app.FreSpectrumSwitch.Enable = 'on';
                app.FreSpectrumDRange.Enable = 'on';
                app.FreSpectrumLRange.Enable = 'on';
                if(strcmp(value,'ADC'))
                    if( app.IsAdcImg==false)
                        cla(app.ImageShow,"reset");
                        app.IsAdcImg = true;
                        app.IsRfImg = false;
                        app.IsBImg = false;

                        app.FreSpectrumChannel.Visible = 'on';
                        app.FreSpectrumCLabel.Visible = 'on';

                        if(app.IsRunning==false&&~isempty(app.AdcData)&&app.ImageCheck.Value)

                            cla(app.ImageShow,"reset");
                            frame = app.ImgaeSlider.Value+1;
                            temp = app.AdcData{frame};
                            temp = permute(temp,[2,1,3]);
                            for i = 1:size(temp,3)
                                RawFrames(:,(i-1)*size(temp,2)+1:i*size(temp,2)) = temp(:,:,i);
                            end
                            maxtemp =  abs(max(RawFrames(:)))+1000;
                            imagesc(app.ImageShow,RawFrames,[-maxtemp,maxtemp]);
                            axis(app.ImageShow, 'tight');
                            colormap(app.ImageShow, app.AdcMap);
                            drawnow;
                            frequency_analysis(app);
                        end
                    end
                elseif(strcmp(value,'RF'))

                    if( app.IsRfImg==false)
                        cla(app.ImageShow,"reset");
                        app.IsAdcImg = false;
                        app.IsRfImg = true;
                        app.IsBImg = false;
                    end

                    app.FreSpectrumChannel.Visible = 'off';
                    app.FreSpectrumCLabel.Visible = 'off';


                    if(app.IsRunning==false&&~isempty(app.RfData)&&app.ImageCheck.Value)
                        cla(app.ImageShow,"reset");
                        frame = app.ImgaeSlider.Value+1;
                        rf = app.RfData{frame};

                        scale=max(max(rf));
                        v = rf/scale;

                        plot(app.ImageShow,v+(1:size(rf,2)), 1:size(rf,1));
                        axis(app.ImageShow, 'tight');
                        set(app.ImageShow, 'YDir', 'reverse');
                        frequency_analysis(app);

                    end

                elseif(strcmp(value,'B'))
                    if( app.IsBImg==false)
                        cla(app.ImageShow,"reset");
                        app.IsAdcImg = false;
                        app.IsRfImg = false;
                        app.IsBImg = true;
                    end
                    app.FreSpectrumChannel.Visible = 'off';
                    app.FreSpectrumCLabel.Visible = 'off';
                    app.FreSpectrumSwitch.Enable = 'off';
                    app.FreSpectrumDRange.Enable = 'off';
                    app.FreSpectrumLRange.Enable = 'off';

                    if(app.IsRunning==false&&app.ImageCheck.Value&&~isempty(app.BData))
                        cla(app.ImageShow,"reset");
                        cla(app.FreSpectrumImage,'reset');
                        app.FreSpectrumImage.XTick = [];
                        app.FreSpectrumImage.YTick = [];
                        frame = app.ImgaeSlider.Value+1;
                        if(app.DscFlag.Value==1)

                            bimg = app.BData{frame};
                            image( app.ImageShow,uint8(bimg));
                            axis(app.ImageShow, 'off');
                            drawnow;
                        else

                            bimg = app.BData{frame};
                            x = app.AcqConfig.Rx.rev_line.x;
                            z = linspace(0, app.AcqConfig.Rx.ImageDepth,app.AcqConfig.Rx.sample_num);
                            image( app.ImageShow,x,z,uint8(bimg));
                            drawnow;
                        end
                    end
                else
                    app.FreSpectrumChannel.Visible = 'off';
                    app.FreSpectrumCLabel.Visible = 'off';
                    app.FreSpectrumSwitch.Enable = 'off';
                    app.FreSpectrumDRange.Enable = 'off';
                    app.FreSpectrumLRange.Enable = 'off';

                    cla(app.ImageShow,"reset");
                    app.ImageShow.XTick = [];
                    app.ImageShow.YTick = [];
                    cla(app.FreSpectrumImage,"reset");
                    app.FreSpectrumImage.XTick = [];
                    app.FreSpectrumImage.YTick = [];
                    app.IsAdcImg = false;
                    app.IsRfImg = false;
                    app.IsBImg = false;
                end
            end

        end

        % Menu selected function: SaveRF
        function SaveRFSelected(app, event)
            folderPath = uigetdir('', 'Select Folder to Save');
            if folderPath ~= 0
                currentTime = datetime('now');
                currentTime.Format = 'yyyyMMddHHmmss';
                timeStr = char(currentTime);  % 将 datetime 转换为 char 类型的字符串
                fullPath = fullfile(folderPath, timeStr);
                if ~exist(fullPath,'dir')
                    mkdir(fullPath);
                end
                Acq_Config = app.AcqConfig;
                save([fullPath,filesep,'Acq_Config.mat'],'Acq_Config','-v7.3');
                RF_Data = app.RfData;
                save([fullPath,filesep,'RF_Data.mat'],"RF_Data",'-v7.3');
                uialert(app.SignalProcessUIFigure, ['RF File saved to: ', fullPath], 'Save Success', ...
                    'Icon', 'success', 'Modal', true);
            else
                uialert(app.SignalProcessUIFigure, 'RF File save was canceled', 'Cancel', ...
                    'Icon', 'warning', 'Modal', false);
            end

        end

        % Menu selected function: SaveB
        function SaveBSelected(app, event)
            folderPath = uigetdir('', 'Select Folder to Save');
            if folderPath ~= 0
                currentTime = datetime('now');
                currentTime.Format = 'yyyyMMddHHmmss';
                timeStr = char(currentTime);  % 将 datetime 转换为 char 类型的字符串
                fullPath = fullfile(folderPath, timeStr);
                if ~exist(fullPath,'dir')
                    mkdir(fullPath);
                end
                Acq_Config = app.AcqConfig;
                save([fullPath,filesep,'Acq_Config.mat'],'Acq_Config','-v7.3');
                B_Data = app.BData;
                save([fullPath,filesep,'B_Data.mat'],"B_Data",'-v7.3');
                uialert(app.SignalProcessUIFigure, ['B Img File saved to: ', fullPath], 'Save Success', ...
                    'Icon', 'success', 'Modal', true);
            else
                uialert(app.SignalProcessUIFigure, 'B Img File save was canceled', 'Cancel', ...
                    'Icon', 'warning', 'Modal', false);
            end

        end

        % Value changed function: FreSpectrumSwitch
        function FreSpectrumSwitchValueChanged(app, event)
            if(app.FreSpectrumSwitch.Value==1)
                if(~strcmp(app.ImageType.Value,'None'))
                    frequency_analysis(app);
                else
                    cla(app.FreSpectrumImage,"reset");
                    app.FreSpectrumImage.XTick = [];
                    app.FreSpectrumImage.YTick = [];
                end
            else
                cla(app.FreSpectrumImage,"reset");
                app.FreSpectrumImage.XTick = [];
                app.FreSpectrumImage.YTick = [];
            end
        end

        % Value changed function: ImageCheck
        function ImageCheckValueChanged(app, event)
            value = app.ImageCheck.Value;
            if(value==1)
                imgname = app.ImageType.Value;

                if(strcmp(imgname,'ADC'))
                    app.IsAdcImg = true;
                    app.IsRfImg = false;
                    app.IsBImg = false;
                    cla(app.ImageShow,"reset");
                    if(app.IsRunning==false&&~isempty(app.AdcData))

                        cla(app.ImageShow,"reset");
                        frame = app.ImgaeSlider.Value+1;
                        temp = app.AdcData{frame};
                        temp = permute(temp,[2,1,3]);
                        for i = 1:size(temp,3)
                            RawFrames(:,(i-1)*size(temp,2)+1:i*size(temp,2)) = temp(:,:,i);
                        end

                        maxtemp =  abs(max(RawFrames(:)))+1000;
                        imagesc(app.ImageShow,RawFrames,[-maxtemp,maxtemp]);
                        axis(app.ImageShow, 'tight');
                        colormap(app.ImageShow, app.AdcMap);
                        drawnow;
                        frequency_analysis(app)
                    end

                elseif(strcmp(imgname,'RF'))

                    cla(app.ImageShow,"reset");
                    app.IsAdcImg = false;
                    app.IsRfImg = true;
                    app.IsBImg = false;

                    if(app.IsRunning==false&&~isempty(app.RfData))
                        cla(app.ImageShow,"reset");
                        frame = app.ImgaeSlider.Value+1;
                        rf = app.RfData{frame};

                        scale=max(max(rf));
                        v = rf/scale;

                        plot(app.ImageShow,v+(1:size(rf,2)), 1:app.AcqConfig.Rx.sample_num);
                        axis(app.ImageShow, 'tight');
                        set(app.ImageShow, 'YDir', 'reverse');
                        frequency_analysis(app)

                    end

                elseif(strcmp(imgname,'B'))

                    cla(app.ImageShow,"reset");
                    cla(app.FreSpectrumImage,'reset');
                    app.FreSpectrumImage.XTick = [];
                    app.FreSpectrumImage.YTick = [];
                    app.IsAdcImg = false;
                    app.IsRfImg = false;
                    app.IsBImg = true;
                    if(app.IsRunning==false&&~isempty(app.BData))
                        cla(app.ImageShow,"reset");

                        frame = app.ImgaeSlider.Value+1;

                        if(app.DscFlag.Value==1)

                            bimg = app.BData{frame};
                            image( app.ImageShow,uint8(bimg));
                            axis(app.ImageShow, 'off');
                            drawnow;
                        else

                            bimg = app.BData{frame};
                            x = app.AcqConfig.Rx.rev_line.x;
                            z = linspace(0, app.AcqConfig.Rx.ImageDepth,app.AcqConfig.Rx.sample_num);
                            image( app.ImageShow,x,z,uint8(bimg));
                            drawnow;

                        end
                    end
                else
                    cla(app.ImageShow,"reset");
                    app.ImageShow.XTick = [];
                    app.ImageShow.YTick = [];
                    cla(app.FreSpectrumImage,"reset");
                    app.FreSpectrumImage.XTick = [];
                    app.FreSpectrumImage.YTick = [];
                    app.IsAdcImg = false;
                    app.IsRfImg = false;
                    app.IsBImg = false;
                end

            else
                cla(app.FreSpectrumImage,"reset");
                app.FreSpectrumImage.XTick = [];
                app.FreSpectrumImage.YTick = [];
            end
        end

        % Value changed function: FreSpectrumDRange
        function FreSpectrumDRangeValueChanged(app, event)
            if(~strcmp(app.ImageType.Value,'None')&&app.FreSpectrumSwitch.Value==1)
                frequency_analysis(app);
            end
        end

        % Value changed function: FreSpectrumChannel
        function FreSpectrumChannelValueChanged(app, event)
            if(~strcmp(app.ImageType.Value,'None')&&app.FreSpectrumSwitch.Value==1)
                frequency_analysis(app);
            end

        end

        % Value changed function: FreSpectrumLRange
        function FreSpectrumLRangeValueChanged(app, event)
            if(~strcmp(app.ImageType.Value,'None')&&app.FreSpectrumSwitch.Value==1)
                frequency_analysis(app);
            end

        end

        % Value changed function: ImageDepth
        function ImageDepthValueChanged(app, event)
            update_paras(app);
            % imagedepth = str2double(app.ImageDepth.Value);
            % if(imagedepth<0)
            %     uialert(app.SignalProcessUIFigure, 'The depth must be a value greater than 0', 'Info', ...
            %         'Icon', 'info', 'Modal', true);
            %     return;
            %
            % end
            % para.Tx.sos = str2double(app.SoundSpeed.Value);
            % para.Tx.fs = str2double(app.SampleRate.Value);
            % %%          采样点数
            % [~,revpt,~ ]= SampleInfo(para,imagedepth);
            % if(revpt>3968)
            %     uialert(app.SignalProcessUIFigure,'The number of sampling points must be less than 3968 points,Please set the sound speed or imaging depth appropriately', 'Failed', ...
            %         'Icon', 'error', 'Modal', true);
            %     return;
            % end
            % if(revpt<519)
            %     uialert(app.SignalProcessUIFigure,'The number of sampling points must be less than 519 points,Please set the sound speed or imaging depth appropriately', 'Failed', ...
            %         'Icon', 'error', 'Modal', true);
            %     return;
            % end

        end

        % Button pushed function: PostProcessRun
        function PostProcessRunButtonPushed(app, event)
            PostProcssPara(app);
            Value = app.ImgaeSlider.Value;
            app.ImageLoc.Value = num2str(Value);
            app.ImageCheck.Value = 1;
            frame = Value+1;
            rf_data = app.RfData{frame};
            if(app.DscFlag.Value==1)
                temp = BProcess(rf_data, app.AcqConfig.Rx.fs,app.AcqConfig.Tx.sos,app.AcqConfig.Rx.rev_line, app.AcqConfig.Probe,app.postprocess,1);
                image( app.ImageShow,uint8(temp));
                axis(app.ImageShow, 'off');
                drawnow;
            else
                temp = BProcess(rf_data, app.AcqConfig.Rx.fs,app.AcqConfig.Tx.sos,app.AcqConfig.Rx.rev_line, app.AcqConfig.Probe,app.postprocess,0);
                x = app.AcqConfig.Rx.rev_line.x;
                z = linspace(0, app.AcqConfig.Rx.ImageDepth,app.AcqConfig.Rx.sample_num);
                image( app.ImageShow,x,z,uint8(temp));
                drawnow;
            end
            app.BData{frame} = temp;
            app.ImageType.Value = 'B';
            app.IsBImg = true;
            app.IsAdcImg = false;
            app.IsRfImg = false;
            app.FreSpectrumSwitch.Enable = 'off';
            app.FreSpectrumChannel.Visible = 'off';
            app.FreSpectrumCLabel.Visible = 'off';
            app.FreSpectrumDRange.Enable = 'off';
            app.FreSpectrumLRange.Enable = 'off';
            cla(app.FreSpectrumImage,'reset');
            app.FreSpectrumImage.XTick = [];
            app.FreSpectrumImage.YTick = [];

        end

        % Value changed function: CaptureSpeed
        function CaptureSpeedValueChanged(app, event)
            value = round(str2double(app.CaptureSpeed.Value));
            app.CaptureSpeed.Value = num2str(value);
            if(value<=30)
                uialert(app.SignalProcessUIFigure, 'The acquisition speed must be greater than 30', 'Warning', ...
                    'Icon', 'warning', 'Modal', true);
                app.Run.Enable = 'off';
                app.PostProcessRun.Visible = 'off';
                return;
            end
            app.Run.Enable = 'on';
            if(value>100)

                type = app.BeamType.Value;
                if(strcmp(type,'Focus Wave'))
                    app.PostProcessRun.Visible = 'off';
                    app.ImageCheck.Value = 0;
                    app.ImageCheck.Enable = 'off';
                    app.ImageType.Value = 'ADC';
                    app.FreSpectrumSwitch.Value = 0;
                    app.FreSpectrumSwitch.Enable = 'off';
                    app.FreSpectrumChannel.Visible = 'on';
                    app.FreSpectrumCLabel.Visible = 'on';
                    app.FreSpectrumChannel.Enable = 'off';
                    app.FreSpectrumDRange.Enable = 'off';
                    app.FreSpectrumLRange.Enable = 'off';
                    app.SaveB.Enable = 'off';
                    app.SaveRF.Enable = 'off';
                    app.SaveADC.Enable = 'off';
                    update_paras(app);
                    uialert(app.SignalProcessUIFigure, 'Only ADC data is collected when the acquisition speed exceeds 100 frames /s', 'Info', ...
                        'Icon', 'info', 'Modal', true);
                end

            else
                type = app.BeamType.Value;
                if(strcmp(type,'Focus Wave'))

                    app.ImageCheck.Enable = 'on';
                    app.FreSpectrumSwitch.Enable = 'on';
                    if(strcmp(app.ImageType.Value ,'ADC'))
                        app.FreSpectrumChannel.Visible = 'on';
                        app.FreSpectrumCLabel.Visible = 'on';
                        app.FreSpectrumCLabel.Enable = 'on';

                    end
                    app.FreSpectrumChannel.Enable = 'on';
                    app.FreSpectrumDRange.Enable = 'on';
                    app.FreSpectrumLRange.Enable = 'on';
                    app.SaveB.Enable = 'on';
                    app.SaveRF.Enable = 'on';
                    app.SaveADC.Enable = 'on';
                    app.ImageLoc.Enable = 'on';
                    app.ImgaeSlider.Enable = 'on';
                else
                    app.FreSpectrumChannel.Enable = 'off';
                    app.FreSpectrumDRange.Enable = 'off';
                    app.FreSpectrumLRange.Enable = 'off';
                    app.SaveB.Enable = 'off';
                    app.SaveRF.Enable = 'off';
                    app.SaveADC.Enable = 'off';
                    app.ImageLoc.Enable = 'off';
                    app.ImgaeSlider.Enable = 'off';
                    app.ImageType.Value = 'B';
                    app.PostProcessRun.Visible = 'off';

                end


            end
        end

        % Value changed function: DynamicRange
        function DynamicRangeValueChanged(app, event)
            value = str2double(app.DynamicRange.Value);
            if(value<30)
                uialert(app.SignalProcessUIFigure, 'Dynamic range input range is 30~140', 'Warning', ...
                    'Icon', 'info', 'Modal', false);

            end
            if(value>140)
                uialert(app.SignalProcessUIFigure, 'Dynamic range input range is 30~140', 'Warning', ...
                    'Icon', 'info', 'Modal', false);
            end

        end

        % Value changed function: ScanLines
        function ScanLinesValueChanged(app, event)
            value = str2double(app.ScanLines.Value);
            if(value>210)
                uialert(app.SignalProcessUIFigure, 'The line density must be less than 210', 'Warning', ...
                    'Icon', 'warning', 'Modal', false);
                app.ScanLines.Value = '210';
            elseif(value<2)
                uialert(app.SignalProcessUIFigure, 'The line density must be greater than 2', 'Warning', ...
                    'Icon', 'warning', 'Modal', false);
                app.ScanLines.Value = '1';

            end

        end

        % Value changed function: FilterCoef
        function FilterCoefValueChanged(app, event)
            value =  str2double(app.FilterCoef.Value);
            if(value>=1)
                uialert(app.SignalProcessUIFigure, 'The filter coefficient must be greater than 0 and less than 1', 'Info', ...
                    'Icon', 'info', 'Modal', false);
                app.FilterCoef.Value = '0.5';
            elseif(value<0)
                uialert(app.SignalProcessUIFigure, 'The filter coefficient must be greater than 0 and less than 1', 'Info', ...
                    'Icon', 'info', 'Modal', false);
                app.FilterCoef.Value = '0.5';
            end
        end

        % Value changed function: FilterOrder
        function FilterOrderValueChanged(app, event)
            value = str2double(app.FilterOrder.Value);
            if(value>128)
                uialert(app.SignalProcessUIFigure, 'The filter order must be greater than 8 and less than 128', 'Info', ...
                    'Icon', 'info', 'Modal', false);
                app.FilterOrder.Value = '128';
            elseif(value<8)
                uialert(app.SignalProcessUIFigure, 'The filter order must be greater than 8 and less than 128', 'Info', ...
                    'Icon', 'info', 'Modal', false);
                app.FilterOrder.Value = '8';
            end
        end

        % Value changed function: MinEmitAper
        function MinEmitAperValueChanged(app, event)
            value = str2double(app.MinEmitAper.Value);
            aper = min(app.Channel,app.AcqConfig.Probe.element_num);

            if(value>aper)
                uialert(app.SignalProcessUIFigure, 'The maximum aperture must be smaller than the number of machine channels', 'Info', ...
                    'Icon', 'info', 'Modal', false);
                app.MinEmitAper.Value = num2str(aper);
                % elseif(value<8)
                %     uialert(app.SignalProcessUIFigure, 'The minimum aperture must be greater than 8', 'Info', ...
                %         'Icon', 'info', 'Modal', false);
                %     app.MinEmitAper.Value = num2str(2);
            end
        end

        % Value changed function: MinRevAper
        function MinRevAperValueChanged(app, event)
            value = str2double(app.MinRevAper.Value);
            aper = min(app.Channel,app.AcqConfig.Probe.element_num);
            if(value>aper)
                uialert(app.SignalProcessUIFigure, 'The maximum aperture must be smaller than the number of machine channels', 'Info', ...
                    'Icon', 'info', 'Modal', false);
                app.MaxRevAper.Value = num2str(aper);
            elseif(value<2)
                uialert(app.SignalProcessUIFigure, 'The minimum aperture must be greater than 8', 'Info', ...
                    'Icon', 'info', 'Modal', false);
                app.MinRevAper.Value = num2str(2);

            end
        end

        % Value changed function: MaxEmitAper
        function MaxEmitAperValueChanged(app, event)
            value =  str2double(app.MaxEmitAper.Value);
            aper = min(app.Channel,app.AcqConfig.Probe.element_num);

            if(value>aper)
                uialert(app.SignalProcessUIFigure, 'The maximum aperture must be smaller than the number of machine channels', 'Info', ...
                    'Icon', 'info', 'Modal', false);
                app.MaxEmitAper.Value = num2str(aper);
                % elseif(value<2)
                %     uialert(app.SignalProcessUIFigure, 'The minimum aperture must be greater than 8', 'Info', ...
                %         'Icon', 'info', 'Modal', false);
                %     app.MaxEmitAper.Value = num2str(2);
            end
        end

        % Value changed function: MaxRevAper
        function MaxRevAperValueChanged(app, event)
            value = str2double(app.MaxRevAper.Value);
            aper = min(app.Channel,app.AcqConfig.Probe.element_num);

            if(value>aper)
                uialert(app.SignalProcessUIFigure, 'The maximum aperture must be smaller than the number of machine channels', 'Info', ...
                    'Icon', 'info', 'Modal', false);
                app.MaxRevAper.Value =  num2str(aper);
            elseif(value<2)
                uialert(app.SignalProcessUIFigure, 'The minimum aperture must be greater than 8', 'Info', ...
                    'Icon', 'info', 'Modal', false);
                app.MaxRevAper.Value =  num2str(2);
            end
        end

        % Value changed function: GlobalDigitalGain
        function GlobalDigitalGainValueChanged(app, event)
            value =  str2double(app.GlobalDigitalGain.Value);
            if(value>40)
                uialert(app.SignalProcessUIFigure, 'The global gain range is between -40 and 40db', 'Info', ...
                    'Icon', 'info', 'Modal', false);
                app.GlobalDigitalGain.Value = '40';
            elseif(value<-40)
                uialert(app.SignalProcessUIFigure, 'The global gain range is between -40 and 40db', 'Info', ...
                    'Icon', 'info', 'Modal', false);
                app.GlobalDigitalGain.Value = '-40';
            end
        end

        % Value changed function: DigitalGainValue
        function DigitalGainChanged(app, event)

            dgain_value = str2double(strsplit( app.DigitalGainValue.Value));
            dgain_value(dgain_value<-40) = -40;
            dgain_value(dgain_value>40) = 40;

            app.DigitalGainValue.Value = addchars(app,dgain_value);
        end

        % Value changed function: EmitPolarity
        function EmitPolarityValueChanged(app, event)
            value = str2double(app.EmitPolarity.Value);
            if(value~=0&&value~=1)
                uialert(app.SignalProcessUIFigure, 'The emission polarity input value must be either 0 or 1', 'Info', ...
                    'Icon', 'info', 'Modal', false);
            end


        end

        % Value changed function: SoundSpeed
        function SoundSpeedValueChanged(app, event)
            update_paras(app);
        end

        % Value changed function: SampleRate
        function SampleRateValueChanged(app, event)

            update_paras(app);
        end

        % Value changed function: BeamType
        function BeamTypeValueChanged(app, event)
            value = app.BeamType.Value;
            if(strcmp(value,'Focus Wave'))
                app.EmitPlaneAngle.Visible = 'off';
                app.EmitPlaneAngleLabel.Visible = 'off';
                app.EmitFocus.Visible = 'on';
                app.EmitFocusLabel.Visible = 'on';
                app.PlaneWaveImageRegion.Visible = 'off';
                app.Emit_Aperture_Parameters.Visible = 'on';
                app.FreSpectrumSwitch.Enable = "on";
                prf = round(str2double(app.CaptureSpeed.Value));


                if(prf>100)

                    app.ImageCheck.Enable = 'off';
                    app.FreSpectrumSwitch.Enable = 'off';
                    app.FreSpectrumChannel.Visible = 'off';
                    app.FreSpectrumCLabel.Visible = 'off';
                    app.FreSpectrumChannel.Enable = 'off';
                    app.FreSpectrumDRange.Enable = 'off';
                    app.FreSpectrumLRange.Enable = 'off';
                    app.SaveB.Enable = 'off';
                    app.SaveRF.Enable = 'off';
                    app.SaveADC.Enable = 'off';
                    app.ImageLoc.Enable = 'off';
                    app.ImgaeSlider.Enable = 'off';

                elseif(prf>30)

                    app.PostProcessRun.Visible = 'off';
                    app.ImageCheck.Value = 0;
                    app.ImageCheck.Enable = 'off';
                    app.ImageType.Value = 'ADC';
                    app.FreSpectrumSwitch.Value = 0;
                    app.FreSpectrumSwitch.Enable = 'off';
                    app.FreSpectrumChannel.Visible = 'on';
                    app.FreSpectrumCLabel.Visible = 'on';
                    app.FreSpectrumChannel.Enable = 'off';
                    app.FreSpectrumDRange.Enable = 'off';
                    app.FreSpectrumLRange.Enable = 'off';
                    app.SaveB.Enable = 'on';
                    app.SaveRF.Enable = 'on';
                    app.SaveADC.Enable = 'on';
                    app.ImageLoc.Enable = 'on';
                    app.ImgaeSlider.Enable = 'on';

                end


            elseif (strcmp(value,'Plane Wave'))
                app.EmitFocus.Visible = 'off';
                app.EmitFocusLabel.Visible = 'off';
                app.EmitPlaneAngle.Visible = 'on';
                app.EmitPlaneAngleLabel.Visible = 'on';
                app.PlaneWaveImageRegion.Visible = 'on';
                app.Emit_Aperture_Parameters.Visible = 'off';
                app.ImageType.Value = 'B';
                app.CaptureSpeed.Value = '300';
                app.FreSpectrumSwitch.Enable = "off";
                app.PostProcessRun.Visible = 'off';
                % app.ImageCheck.Value = 1;
                app.ImageCheck.Enable = 'on';
                app.FreSpectrumSwitch.Value = 0;
                app.FreSpectrumSwitch.Enable = 'off';
                app.FreSpectrumChannel.Visible = 'on';
                app.FreSpectrumCLabel.Visible = 'on';
                app.FreSpectrumChannel.Enable = 'off';
                app.FreSpectrumDRange.Enable = 'off';
                app.FreSpectrumLRange.Enable = 'off';
                app.SaveB.Enable = 'off';
                app.SaveRF.Enable = 'off';
                app.SaveADC.Enable = 'off';

                app.ImageLoc.Enable = 'off';
                app.ImgaeSlider.Enable = 'off';

            end

        end

        % Value changed function: ImageRegion_UPW_Auto
        function ImageRegion_UPW_AutoValueChanged(app, event)
            value = app.ImageRegion_UPW_Auto.Value;
            if(value==1)

                % revloc.step_x = app.AcqConfig.Probe.element_pitch/2;
                % revloc.start_x = app.AcqConfig.Probe.element_pos.x(1);
                % revloc.end_x = app.AcqConfig.Probe.element_pos.x(end);
                % revloc.x_num = floor((revloc.end_x- revloc.start_x)/ revloc.step_x);

                revloc.start_x = app.AcqConfig.Probe.element_pos.x(1);
                revloc.end_x = app.AcqConfig.Probe.element_pos.x(end);
                revloc.x_num = app.AcqConfig.Probe.element_num*2;
                revloc.step_x = (revloc.end_x -revloc.start_x)/ (revloc.x_num-1);

                app.Pw_start_x_UPW.Value = num2str(revloc.start_x);
                app.Pw_end_x_UPW.Value = num2str(revloc.end_x);
                app.Pw_step_x_UPW.Value = num2str(revloc.step_x);


                revloc.step_z = app.sos/app.AcqConfig.Rx.fs/2;
                revloc.start_z = app.AcqConfig.Probe.image_start;
                revloc.end_z = str2double(app.ImageDepth_UPW.Value);
                revloc.z_num = floor((revloc.end_z- revloc.start_z)/ revloc.step_z);


                app.Pw_start_z_UPW.Value = num2str(revloc.start_z);
                app.Pw_end_z_UPW.Value = num2str(revloc.end_z);
                app.Pw_step_z_UPW.Value = num2str(revloc.step_z);

                app.AcqConfig.Rx.post_process_fs = app.sos/revloc.step_z/2;
                pw_tx_angle = str2double(strsplit(app.EmitPlaneAngle_UPW.Value));
                %%          发射波形
                txwave.voltage = str2double(app.EmitVol_UPW.Value);
                txwave.pulse_num = str2double(app.EmitPulseNum_UPW.Value);
                txwave.pulse_duration = str2double(app.EmitDuration_UPW.Value);
                txwave.pulse_frequency = str2double(app.EmitPulseFre_UPW.Value);
                txwave.pulse_Polarity = str2double(app.EmitPolarity_UPW.Value);
                [tx_info,scanlist,rx_info]=  Tx_beamform_PlaneWave(pw_tx_angle,app.AcqConfig,txwave,revloc);
                app.AcqConfig.Tx.sequence = tx_info.sequence;
                app.ScanList = scanlist;
                app.AcqConfig.Rx.sequence = rx_info.sequence;
                %平面波都是一样的

                startx =  revloc.start_x;
                startz =  revloc.start_z;
                setpx =  revloc.step_x;
                setpz =  revloc.step_z;

                Nx =  revloc.x_num;
                Nz =  revloc.z_num;

                x_axis = (0:setpx:(Nx-1) * setpx)+ startx;
                z_axis = (0:setpz:(Nz-1) * setpz)+ startz;

                app.AcqConfig.Rx.rev_line.x = x_axis;
                app.AcqConfig.Rx.rev_line.z = z_axis;


                [x_grid, z_grid] = meshgrid(x_axis,z_axis);
                x_grid = reshape(x_grid,[Nz*Nx,1]);
                z_grid = reshape(z_grid,[Nz*Nx,1]);
                f_mask = zeros(Nx*Nz,app.AcqConfig.Probe.element_num);

                for i = 1:app.AcqConfig.Probe.element_num
                    f_mask(:,i) = z_grid./abs(x_grid - app.AcqConfig.Probe.element_pos.x(i))/2 > app.AcqConfig.Rx.aperture.fn_value(1);
                end

                for i = 1:size(f_mask,1)
                    temp = f_mask(i,:);
                    loc = temp==1;
                    % if(saper< app.AcqConfig.Rx.aperture.min_Aper)
                    %      %%add
                    % end

                    temp(1,loc) =  app.AcqConfig.Rx.aperture.js_win(floor(linspace(1,256,sum(temp))));
                    f_mask(i,:) = temp;
                end

                app.AcqConfig.Rx.apod  = f_mask;

            end
        end

        % Value changed function: SampleRate_UPW
        function SampleRate_UPWValueChanged(app, event)
            value = str2double(app.SampleRate_UPW.Value);
            app.SysRate = value;

        end

        % Value changed function: SoundSpeed_UPW
        function SoundSpeed_UPWValueChanged(app, event)
            % pause(0.05)
            % para.Tx.sos = str2double(app.SoundSpeed_UPW.Value);
            %
            % imagedepth = str2double(app.ImageDepth_UPW.Value);
            %
            % para.Tx.fs = str2double(app.SampleRate_UPW.Value);

            %%          采样点数
            % [~,revpt,~ ]= SampleInfo(para,imagedepth);
            % if(revpt>3968)
            %     uialert(app.SignalProcessUIFigure,'The number of sampling points must be less than 3968 points,Please set the sound speed or imaging depth appropriately', 'Failed', ...
            %         'Icon', 'error', 'Modal', true);
            %     return;
            % end
            % if(revpt<519)
            %     uialert(app.SignalProcessUIFigure,'The number of sampling points must be less than 519 points,Please set the sound speed or imaging depth appropriately', 'Failed', ...
            %         'Icon', 'error', 'Modal', true);
            %     return;
            % end
            update_paras(app);
        end

        % Value changed function: ImageDepth_UPW
        function ImageDepth_UPWValueChanged(app, event)
            imagedepth = str2double(app.ImageDepth_UPW.Value);
            if(imagedepth<0)
                uialert(app.SignalProcessUIFigure, 'The depth must be a value greater than 0', 'Info', ...
                    'Icon', 'info', 'Modal', true);
                return;

            end
            % para.Tx.sos = str2double(app.SoundSpeed_UPW.Value);
            % para.Tx.fs = str2double(app.SampleRate_UPW.Value);
            %%          采样点数
            % [~,revpt,~ ]= SampleInfo(para,imagedepth);
            % if(revpt>3968)
            %     uialert(app.SignalProcessUIFigure,'The number of sampling points must be less than 3968 points,Please set the sound speed or imaging depth appropriately', 'Failed', ...
            %         'Icon', 'error', 'Modal', true);
            %     return;
            % end
            % if(revpt<519)
            %     uialert(app.SignalProcessUIFigure,'The number of sampling points must be less than 519 points,Please set the sound speed or imaging depth appropriately', 'Failed', ...
            %         'Icon', 'error', 'Modal', true);
            %     return;
            % end
            update_paras(app);
        end

        % Value changed function: EmitVol_UPW
        function EmitVol_UPWValueChanged(app, event)

            value = str2double(app.EmitVol_UPW.Value);
            if(value>95)
                uialert(app.SignalProcessUIFigure, 'The voltage cannot be greater than 100 V and it is not recommended to exceed 70 V', 'Error', ...
                    'Icon', 'error', 'Modal', true);

                app.EmitVol.Value = '95';
            elseif(value>70)
                uialert(app.SignalProcessUIFigure, 'The voltage is not recommended to exceed 70 V', 'Warning', ...
                    'Icon', 'warning', 'Modal', true);
            elseif(value<5)
                uialert(app.SignalProcessUIFigure, 'The voltage is not less than 5 V', 'Error', ...
                    'Icon', 'error', 'Modal', true);
                app.EmitVol.Value = '5';

            end
        end

        % Value changed function: EmitDuration_UPW
        function EmitDuration_UPWValueChanged(app, event)
            value = str2double(app.EmitDuration_UPW.Value);
            if(value~=0&&value~=1)
                uialert(app.SignalProcessUIFigure, 'The emission polarity input value must be either 0 or 1', 'Info', ...
                    'Icon', 'info', 'Modal', false);
            end

        end

        % Value changed function: EmitPulseNum_UPW
        function EmitPulseNum_UPWValueChanged(app, event)

            value = str2double(app.EmitPulseNum_UPW.Value);
            if(mod(value, 0.5)~=0)
                uialert(app.SignalProcessUIFigure, 'The period must be a multiple of 0.5', 'Error', ...
                    'Icon', 'error', 'Modal', true);

            end
        end

        % Value changed function: EmitPolarity_UPW
        function EmitPolarity_UPWValueChanged(app, event)
            value = str2double(app.EmitPolarity_UPW.Value);
            if(value~=0&&value~=1)
                uialert(app.SignalProcessUIFigure, 'The emission polarity input value must be either 0 or 1', 'Info', ...
                    'Icon', 'info', 'Modal', false);
            end
        end

        % Value changed function: CaptureSpeed_UPW
        function CaptureSpeed_UPWValueChanged(app, event)
            update_paras(app);
        end

        % Value changed function: EmitPlaneAngle_UPW
        function EmitPlaneAngle_UPWValueChanged(app, event)
            update_paras(app);
        end

        % Value changed function: MaxRevAper_UPW
        function MaxRevAper_UPWValueChanged(app, event)
            value = str2double(app.MaxRevAper_UPW.Value);
            aper = min(app.Channel,app.AcqConfig.Probe.element_num);

            if(value>aper)
                uialert(app.SignalProcessUIFigure, 'The maximum aperture must be smaller than the number of machine channels', 'Info', ...
                    'Icon', 'info', 'Modal', false);
                app.MaxRevAper_UPW.Value = num2str(aper);
            elseif(value<2)
                uialert(app.SignalProcessUIFigure, 'The minimum aperture must be greater than 8', 'Info', ...
                    'Icon', 'info', 'Modal', false);
                app.MaxRevAper_UPW.Value = num2str(2);
            end
        end

        % Value changed function: MinRevAper_UPW
        function MinRevAper_UPWValueChanged(app, event)
            value = str2double(app.MinRevAper_UPW.Value);
            aper = min(app.Channel,app.AcqConfig.Probe.element_num);

            if(value>aper)
                uialert(app.SignalProcessUIFigure, 'The maximum aperture must be smaller than the number of machine channels', 'Info', ...
                    'Icon', 'info', 'Modal', false);
                app.MinRevAper_UPW.Value = num2str(aper);
            elseif(value<2)
                uialert(app.SignalProcessUIFigure, 'The minimum aperture must be greater than 8', 'Info', ...
                    'Icon', 'info', 'Modal', false);
                app.MinRevAper_UPW.Value = num2str(2);
            end

        end

        % Button pushed function: Run_UPW
        function Run_UPWButtonPushed(app, event)
            if(app.SaveADC_UPW.Value==1)
                % if(isempty( app.upw_folderPath))
                folderPath = uigetdir('', 'Select Folder to Save');
                if folderPath ~= 0
                    currentTime = datetime('now');
                    currentTime.Format = 'yyyyMMddHHmmss';
                    timeStr = char(currentTime);  % 将 datetime 转换为 char 类型的字符串
                    fullPath = fullfile(folderPath, strcat(timeStr,'\ADC'));
                    if ~exist(fullPath,'dir')
                        mkdir(fullPath);
                    end
                    app.upw_folderPath = fullPath;
                else
                    app.upw_folderPath = [];
                    return;
                end

            end
            capture_upw_data(app);
        end

        % Value changed function: FrameRate_UPW
        function FrameRate_UPWValueChanged(app, event)
            value = str2double(app.FrameRate_UPW.Value);
            app.framerate_upw = value;
            if(value>60)
                uialert(app.SignalProcessUIFigure, 'The frame rate cannot be greater than 60', 'Info', ...
                    'Icon', 'info', 'Modal', false);
                app.FrameRate_UPW.Value = num2str(60);
                app.framerate_upw = 60;
            end


        end

        % Value changed function: FilterOrder_UPW
        function FilterOrder_UPWValueChanged(app, event)
            value = str2double(app.FilterOrder_UPW.Value);
            if(value>256)
                uialert(app.SignalProcessUIFigure, 'The filter order must be greater than 16 and less than 256', 'Info', ...
                    'Icon', 'info', 'Modal', false);
                app.FilterOrder.Value = '256';
            elseif(value<16)
                uialert(app.SignalProcessUIFigure, 'The filter order must be greater than 16 and less than 256', 'Info', ...
                    'Icon', 'info', 'Modal', false);
                app.FilterOrder.Value = '16';
            end
        end

        % Button pushed function: PostProcessRun_UPW
        function PostProcessRun_UPWButtonPushed(app, event)

            % dsc_data = Dsc(power_doppler, app.AcqConfig.Probe,scanline,M,N,480,640,image_depth,fs,c);
            cla(app.ImageShow_UPW_2);
            dr = -1*str2double(app.DynamicRange_UPW.Value);

            hImg = imagesc(app.power_dopplers{1},'Parent', app.ImageShow_UPW_2);
            colormap(app.ImageShow_UPW_2,hot);
            clim(app.ImageShow_UPW_2,[dr, 0]);
            axis(app.ImageShow_UPW_2,'tight');
            axis(app.ImageShow_UPW_2,'off');

            for i = 1:size(app.power_dopplers,2)
                newData = app.power_dopplers{i};
                % 只更新图像数据，不要重新调用 imagesc，提高效率
                set(hImg, 'CData', newData);
                title(app.ImageShow_UPW_2, sprintf('Frame %d', i));
                % 刷新 GUI
                drawnow;
                pause(0.5);
            end

        end

        % Button pushed function: Run_BF_UPW
        function Run_BF_UPWButtonPushed(app, event)

            update_paras(app);

            savedata_para.startframe = str2double(app.BF_FrameStart_UPW.Value);
            savedata_para.endframe = str2double(app.BF_FrameEnd_UPW.Value);
            savedata_para.packsize = str2double(app.BF_Data_PackSize_UPW.Value);
            if(mod(savedata_para.packsize,numel(str2double(app.EmitPlaneAngle_UPW)))~=0)
                uialert(app.SignalProcessUIFigure, 'The number of data stored must be a multiple of the transmission sequence', 'Info', ...
                    'Icon', 'info', 'Modal', false);
                return
            end

            if(savedata_para.endframe>app.frame_nums||savedata_para.packsize>app.frame_nums)
                uialert(app.SignalProcessUIFigure, 'Invalid parameters, please reset the parameters', 'Info', ...
                    'Icon', 'info', 'Modal', false);
                return
            end

            if(savedata_para.endframe>app.frame_nums||savedata_para.packsize>app.frame_nums)
                uialert(app.SignalProcessUIFigure, 'Invalid parameters, please reset the parameters', 'Info', ...
                    'Icon', 'info', 'Modal', false);
                return
            end
            savedata_para.NumsPerFile = app.NumsPerFile;
            savedata_para.frame_nums = app.frame_nums;

            folderPath = uigetdir('', 'Select Folder to Save  BF Data');
            if folderPath ~= 0
                currentTime = datetime('now');
                currentTime.Format = 'yyyyMMddHHmmss';
                timeStr = char(currentTime);  % 将 datetime 转换为 char 类型的字符串
                fullPath = fullfile(folderPath, strcat(timeStr,'\IQ\'));
                if ~exist(fullPath,'dir')
                    mkdir(fullPath);
                end
                app.upw_BFPath = fullPath;
            else
                app.upw_BFPath = [];
                return;
            end
            demopara.demo_depth = app.postprocess.demo_depth ;
            demopara.demo_value = app.postprocess.demo_value ;

            demopara.filtercoef = app.postprocess.filtercoef ;
            demopara.filterorder = app.postprocess.filterorder;
            demopara.filtertype = app.postprocess.filtertype;

            % if(app.ShowImg_UPW.Value==1)
            cla(app.ImageShow_UPW,"reset");

            PlaneWave_IQBF_GPU(app.AcqConfig,demopara,app.upw_folderPath,app.upw_BFPath,savedata_para,1,app.ImageShow_UPW);
            % else
            %     PlaneWave_IQBF_GPU(app.AcqConfig,demopara,app.upw_folderPath,app.upw_BFPath,savedata_para,1,app.ImageShow_UPW);
            %
            % end

        end

        % Button down function: BTab
        function BTabButtonDown(app, event)
            app.SaveADC.Visible = 'on';
            app.SaveRF.Visible = 'on';
            app.SaveB.Visible = 'on';
            app.LoadPara_UPW.Visible = 'off';
            app.FusDataAnaly.Visible = 'off';
            app.modetype = 'B';
            update_paras(app);

        end

        % Button down function: FUSTab
        function FUSTabButtonDown(app, event)
            app.SaveADC.Visible = 'off';
            app.SaveRF.Visible = 'off';
            app.SaveB.Visible = 'off';
            app.LoadPara_UPW.Visible = 'on';
            app.FusDataAnaly.Visible = 'on';
            app.modetype = 'UltraPW';
            update_paras(app);
        end

        % Menu selected function: LoadPara_UPW
        function LoadPara_UPWMenuSelected2(app, event)
            app.SaveADC.Visible = 'off';
            app.SaveRF.Visible = 'off';
            app.SaveB.Visible = 'off';
            folderpath = uigetdir('D:\');
            if(folderpath==0)
                return;
            end
            [fs,prf,sampleNum,scanLine,imagedepth,~,~,frame_num,numsPerFile,steer,scan] = read_adc_para(strcat(folderpath,'\Param.txt'),'plane_wave');
            start_x = scan.start_x(1:end);
            app.SampleRate_UPW.Value = num2str(fs);
            app.CaptureSpeed_UPW.Value = num2str(prf);
            app.EmitPlaneAngle_UPW.Value = num2str(steer);
            app.Pw_start_x_UPW.Value =  num2str(start_x(1));
            app.Pw_step_x_UPW.Value =  num2str(scan.step_x(1));
            app.Pw_start_z_UPW.Value =  num2str(scan.start_z(1));
            app.Pw_step_z_UPW.Value =  num2str(scan.step_z(1));
            app.Pw_end_z_UPW.Value = num2str(imagedepth);
            app.Pw_end_x_UPW.Value = num2str(start_x(end));
            app.ImageDepth_UPW.Value = num2str(imagedepth);
            app.BF_FrameStart_UPW.Value =  num2str(1);
            app.BF_FrameEnd_UPW.Value =  num2str(frame_num);
            app.BF_Data_PackSize_UPW.Value = '1';
            app.upw_folderPath = folderpath;
            app.frame_nums = frame_num;
            app.NumsPerFile = numsPerFile;
            app.PostProcessRun_UPW.Visible = 'on';
            app.FusDataAnaly.Visible = 'on';


        end

        % Value changed function: ColorROI_UPW_Check
        function ColorROI_UPW_CheckValueChanged(app, event)
            value = app.ColorROI_UPW_Check.Value;
            if(value==1)
                enableRectangleDrawing(app);
            else
                clearRectangle(app);
                app.ColorROI_UPW_TextArea.Value= '';
            end

        end

        % Button pushed function: Run_SVD_UPW
        function Run_SVD_UPWButtonPushed(app, event)
            disp('Start SVD filtering')

            if(app.SavePow_UPW.Value==1)
                folderPath = uigetdir('', 'Select Folder to Save Data');
                if(folderPath==0)
                    app.upw_DOPPLERPath =[];
                    return;
                else
                    currentTime = datetime('now');
                    currentTime.Format = 'yyyyMMddHHmmss';
                    timeStr = char(currentTime);  % 将 datetime 转换为 char 类型的字符串
                    fullPath = fullfile(folderPath, strcat(timeStr,'\doppler\'));
                    if ~exist(fullPath,'dir')
                        mkdir(fullPath);
                    end
                    app.upw_DOPPLERPath =    fullPath;
                end
            end

            txt = app.ColorROI_UPW_TextArea.Value(2:5);   % 第3到6行是四角点
            corners_rc = zeros(4,2);
            for k = 1:4
                corners_rc(k,:) = sscanf(txt{k}, '[%d,%d]');
            end

            row_loc = corners_rc(1,1):corners_rc(3,1);
            col_loc = corners_rc(1,2):corners_rc(2,2);


            files = sort_files(app.upw_BFPath,'*.mat');
            num_files =length (files);
            Packsize = str2double(app.Svd_Packsize.Value);

            buffer = [];
            app.power_dopplers = {};
            for f = 1:num_files
                % 只加载当前块
                load(fullfile(files(f).folder ,files(f).name));
                block = bfdata(row_loc,col_loc,:);               % 200x200x10
                [M,N,P] = size(block);

                for s = 1:P
                    buffer = cat(3, buffer, block(:,:,s));
                    if size(buffer,3) == Packsize
                        groups = buffer;
                        buffer = [];
                        temp = reshape(groups,M*N,Packsize);

                        [U,S,V] = svd(temp,'econ');
                        ord1 = str2double(app.Svd_L.Value);
                        ord2 = str2double(app.Svd_H.Value);
                        S_filt = zeros(size(S));
                        for k = ord1:ord2
                            S_filt(k,k) = S(k,k);
                        end
                        filtered_mat = U*S_filt*V';

                        filtered_mat = mean(abs(filtered_mat).^2,2);

                        power_doppler = reshape(filtered_mat,[M N]);

                        app.power_dopplers{end+1} = log_compressed(power_doppler);

                    end
                end
            end
            if( isempty(app.power_dopplers))
                 % disp('Start SVD filtering')
                                 uialert(app.SignalProcessUIFigure,'SVD Parameter setting error', 'Error', ...
                    'Icon', 'error', 'Modal', true);
                 return;
            end
            disp('Complete SVD filtering')


            if(app.SavePow_UPW.Value==1)
                for i = 1:size( app.power_dopplers,2)
                    data(:,:,i) = app.power_dopplers{i};
                end
                fus_data.Data = data;
                if(size( app.power_dopplers,2)==1)
                    fus_data.Type = 'plane';
                    fus_data.Direction = 'DV.LR';
                else
                    fus_data.Type = 'fusplane';
                    fus_data.Direction = 'DV.LR.TM';
                end
                fus_data.VoxelSize = [round(str2double(app.Pw_step_z_UPW.Value)*1e6),round(str2double(app.Pw_step_x_UPW.Value)*1e6)];
                save(strcat(app.upw_DOPPLERPath,'fus_data'),'fus_data');

                uialert(app.SignalProcessUIFigure, ['Doppler Data saved to: ', app.upw_DOPPLERPath], 'Save Success', ...
                    'Icon', 'success', 'Modal', true);

            end
        end

        % Value changed function: SavePow_UPW
        function SavePow_UPWValueChanged(app, event)
            % value = app.SavePow_UPW.Value;
            % if(value ==1)
            %
            %     folderPath = uigetdir('', 'Select Folder to Save Data');
            %     if(folderPath==0)
            %         app.upw_DOPPLERPath =[];
            %         return;
            %     else
            %         currentTime = datetime('now');
            %         currentTime.Format = 'yyyyMMddHHmmss';
            %         timeStr = char(currentTime);  % 将 datetime 转换为 char 类型的字符串
            %         fullPath = fullfile(folderPath, strcat(timeStr,'\doppler\'));
            %         if ~exist(fullPath,'dir')
            %             mkdir(fullPath);
            %         end
            %         app.upw_DOPPLERPath = fullPath;
            %     end
            %
            % end
        end

        % Value changed function: ImageRegion_PlaneWave_Auto
        function ImageRegion_PlaneWave_AutoValueChanged(app, event)
            value = app.ImageRegion_PlaneWave_Auto.Value;
            if(value==1)

                revloc.start_x = app.AcqConfig.Probe.element_pos.x(1);
                revloc.end_x = app.AcqConfig.Probe.element_pos.x(end);
                revloc.x_num = app.AcqConfig.Probe.element_num*2;
                revloc.step_x = (revloc.end_x -revloc.start_x)/ (revloc.x_num-1);

                app.Pw_start_x.Value = num2str(revloc.start_x);
                app.Pw_end_x.Value = num2str(revloc.end_x);
                app.Pw_step_x.Value = num2str(revloc.step_x);


                revloc.step_z = app.sos/app.AcqConfig.Rx.fs/2;
                revloc.start_z = app.AcqConfig.Probe.image_start;
                revloc.end_z = str2double(app.ImageDepth.Value);
                revloc.z_num = floor((revloc.end_z- revloc.start_z)/ revloc.step_z);


                app.Pw_start_z.Value = num2str(revloc.start_z);
                app.Pw_end_z.Value = num2str(revloc.end_z);
                app.Pw_step_z.Value = num2str(revloc.step_z);

                app.AcqConfig.Rx.post_process_fs = app.sos/revloc.step_z/2;
                pw_tx_angle = str2double(strsplit(app.EmitPlaneAngle.Value));
                %%          发射波形
                txwave.voltage = str2double(app.EmitVol.Value);
                txwave.pulse_num = str2double(app.EmitPulseNum.Value);
                txwave.pulse_duration = str2double(app.EmitDuration.Value);
                txwave.pulse_frequency = str2double(app.EmitPulseFre.Value);
                txwave.pulse_Polarity = str2double(app.EmitPolarity.Value);
                [tx_info,scanlist,rx_info]=  Tx_beamform_PlaneWave(pw_tx_angle,app.AcqConfig,txwave,revloc);
                app.AcqConfig.Tx.sequence = tx_info.sequence;
                app.ScanList = scanlist;
                app.AcqConfig.Rx.sequence = rx_info.sequence;
                %平面波都是一样的

                startx =  revloc.start_x;
                startz =  revloc.start_z;
                setpx =  revloc.step_x;
                setpz =  revloc.step_z;

                Nx =  revloc.x_num;
                Nz =  revloc.z_num;

                x_axis = (0:setpx:(Nx-1) * setpx)+ startx;
                z_axis = (0:setpz:(Nz-1) * setpz)+ startz;

                app.AcqConfig.Rx.rev_line.x = x_axis;
                app.AcqConfig.Rx.rev_line.z = z_axis;


                [x_grid, z_grid] = meshgrid(x_axis,z_axis);
                x_grid = reshape(x_grid,[Nz*Nx,1]);
                z_grid = reshape(z_grid,[Nz*Nx,1]);
                f_mask = zeros(Nx*Nz,app.AcqConfig.Probe.element_num);

                for i = 1:app.AcqConfig.Probe.element_num
                    f_mask(:,i) = z_grid./abs(x_grid - app.AcqConfig.Probe.element_pos.x(i))/2 > app.AcqConfig.Rx.aperture.fn_value(1);
                end

                for i = 1:size(f_mask,1)
                    temp = f_mask(i,:);
                    loc = temp==1;
                    % if(saper< app.AcqConfig.Rx.aperture.min_Aper)
                    %      %%add
                    % end

                    temp(1,loc) =  app.AcqConfig.Rx.aperture.js_win(floor(linspace(1,256,sum(temp))));
                    f_mask(i,:) = temp;
                end

                app.AcqConfig.Rx.apod  = f_mask;

            end
        end

        % Menu selected function: FusDataAnaly
        function FusDataAnalyMenuSelected(app, event)
            run FusAnalysis.mlapp
            return;
        end

        % Value changed function: BF_Data_PackSize_UPW
        function BF_Data_PackSize_UPWValueChanged(app, event)
            value = str2double(app.BF_Data_PackSize_UPW.Value);
            s = str2double(app.BF_FrameStart_UPW.Value);
            e = str2double(app.BF_FrameEnd_UPW.Value);
            temp =  floor((e-s+1)/value)*value-1+s;
            if( temp<=0)
                app.BF_Data_PackSize_UPW.Value = '1';
            else
                app.BF_FrameEnd_UPW.Value = num2str(temp);

            end

        end

        % Value changed function: BF_FrameStart_UPW
        function BF_FrameStart_UPWValueChanged(app, event)
            value = str2double(app.BF_Data_PackSize_UPW.Value);
            s = str2double(app.BF_FrameStart_UPW.Value);
            e = str2double(app.BF_FrameEnd_UPW.Value);

            temp =  floor((e-s+1)/value)*value-1+s;
            if( temp<=0)
                 app.BF_FrameStart_UPW.Value = '1';

                app.BF_Data_PackSize_UPW.Value = '1';

            else

               app.BF_FrameEnd_UPW.Value = num2str(temp);

            end
        end

        % Value changed function: BF_FrameEnd_UPW
        function BF_FrameEnd_UPWValueChanged(app, event)
            value = str2double(app.BF_Data_PackSize_UPW.Value);
            s = str2double(app.BF_FrameStart_UPW.Value);
            e = str2double(app.BF_FrameEnd_UPW.Value);
            temp =  floor((e-s+1)/value)*value-1+s;
            if( temp<=0)
                app.BF_Data_PackSize_UPW.Value = '1';
                app.BF_FrameEnd_UPW.Value = num2str(app.frame_nums);

            else
                app.BF_FrameEnd_UPW.Value = num2str(temp);

            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SignalProcessUIFigure = uifigure('Visible', 'off');
            colormap(app.SignalProcessUIFigure, 'white');
            app.SignalProcessUIFigure.Name = 'Signal Process';
            app.Menu_Parameters = uimenu(app.SignalProcessUIFigure);
            app.Menu_Parameters.Text = '文件';
            app.SaveADC = uimenu(app.Menu_Parameters);
            app.SaveADC.MenuSelectedFcn = createCallbackFcn(app, @SaveADCMenuSelected, true);
            app.SaveADC.Accelerator = 'A';
            app.SaveADC.Text = '保存 ADC';
            app.SaveRF = uimenu(app.Menu_Parameters);
            app.SaveRF.MenuSelectedFcn = createCallbackFcn(app, @SaveRFSelected, true);
            app.SaveRF.Accelerator = 'R';
            app.SaveRF.Text = '保存 RF';
            app.SaveB = uimenu(app.Menu_Parameters);
            app.SaveB.MenuSelectedFcn = createCallbackFcn(app, @SaveBSelected, true);
            app.SaveB.Accelerator = 'b';
            app.SaveB.Text = '保存 图像';
            app.LoadPara_UPW = uimenu(app.Menu_Parameters);
            app.LoadPara_UPW.MenuSelectedFcn = createCallbackFcn(app, @LoadPara_UPWMenuSelected2, true);
            app.LoadPara_UPW.Text = '加载数据';
            app.FusDataAnaly = uimenu(app.Menu_Parameters);
            app.FusDataAnaly.MenuSelectedFcn = createCallbackFcn(app, @FusDataAnalyMenuSelected, true);
            app.FusDataAnaly.Text = '数据分析';
            app.TabGroup = uitabgroup(app.SignalProcessUIFigure);
            app.BTab = uitab(app.TabGroup);
            app.BTab.Title = 'B';
            app.BTab.ButtonDownFcn = createCallbackFcn(app, @BTabButtonDown, true);
            app.Frontend = uipanel(app.BTab);
            app.Frontend.Title = '前端';
            app.Emit_Wave_Parameters = uipanel(app.Frontend);
            app.Emit_Wave_Parameters.Title = '发射波形';
            app.Label_15 = uilabel(app.Emit_Wave_Parameters);
            app.Label_15.Text = '电压';
            app.EmitVol = uieditfield(app.Emit_Wave_Parameters, 'text');
            app.EmitVol.ValueChangedFcn = createCallbackFcn(app, @EmitVolValueChanged, true);
            app.EmitVol.Value = '35';
            app.Label_16 = uilabel(app.Emit_Wave_Parameters);
            app.Label_16.Text = '周期';
            app.EmitPulseNum = uieditfield(app.Emit_Wave_Parameters, 'text');
            app.EmitPulseNum.ValueChangedFcn = createCallbackFcn(app, @EmitPulseNumValueChanged, true);
            app.EmitPulseNum.Value = '1';
            app.Label_17 = uilabel(app.Emit_Wave_Parameters);
            app.Label_17.Text = '占空比';
            app.EmitDuration = uieditfield(app.Emit_Wave_Parameters, 'text');
            app.EmitDuration.ValueChangedFcn = createCallbackFcn(app, @EmitDurationValueChanged, true);
            app.EmitDuration.Value = '1';
            app.Label_18 = uilabel(app.Emit_Wave_Parameters);
            app.Label_18.Text = '频率';
            app.EmitPulseFre = uieditfield(app.Emit_Wave_Parameters, 'text');
            app.EmitPulseFre.Value = '15000000';
            app.Label_19 = uilabel(app.Emit_Wave_Parameters);
            app.Label_19.Text = '极性';
            app.EmitPolarity = uieditfield(app.Emit_Wave_Parameters, 'text');
            app.EmitPolarity.ValueChangedFcn = createCallbackFcn(app, @EmitPolarityValueChanged, true);
            app.EmitPolarity.Value = '0';
            app.EmitFocus = uieditfield(app.Frontend, 'text');
            app.EmitFocus.Value = '0.02';
            app.Label_5 = uilabel(app.Frontend);
            app.Label_5.Text = '成像深度';
            app.ImageDepth = uieditfield(app.Frontend, 'text');
            app.ImageDepth.ValueChangedFcn = createCallbackFcn(app, @ImageDepthValueChanged, true);
            app.ImageDepth.Value = '0.03';
            app.Label_6 = uilabel(app.Frontend);
            app.Label_6.Text = '线密度';
            app.Emit_Aperture_Parameters = uipanel(app.Frontend);
            app.Emit_Aperture_Parameters.Title = '发射孔径';
            app.EditField_9Label = uilabel(app.Emit_Aperture_Parameters);
            app.EditField_9Label.Text = '深度';
            app.EmitAperDepth = uieditfield(app.Emit_Aperture_Parameters, 'text');
            app.EmitAperDepth.Value = '0 0.04';
            app.FEditField_4Label = uilabel(app.Emit_Aperture_Parameters);
            app.FEditField_4Label.Text = 'F#';
            app.EmitAperFnum = uieditfield(app.Emit_Aperture_Parameters, 'text');
            app.EmitAperFnum.Value = '4 4';
            app.EditField_9Label_2 = uilabel(app.Emit_Aperture_Parameters);
            app.EditField_9Label_2.Text = '最小孔径';
            app.MinEmitAper = uieditfield(app.Emit_Aperture_Parameters, 'text');
            app.MinEmitAper.ValueChangedFcn = createCallbackFcn(app, @MinEmitAperValueChanged, true);
            app.MinEmitAper.Value = '32';
            app.EditField_9Label_3 = uilabel(app.Emit_Aperture_Parameters);
            app.EditField_9Label_3.Text = '最大孔径';
            app.MaxEmitAper = uieditfield(app.Emit_Aperture_Parameters, 'text');
            app.MaxEmitAper.ValueChangedFcn = createCallbackFcn(app, @MaxEmitAperValueChanged, true);
            app.MaxEmitAper.Value = '128';
            app.ScanLines = uieditfield(app.Frontend, 'text');
            app.ScanLines.ValueChangedFcn = createCallbackFcn(app, @ScanLinesValueChanged, true);
            app.ScanLines.Value = '128';
            app.Receive_Aperture_Parameters = uipanel(app.Frontend);
            app.Receive_Aperture_Parameters.Title = '接收孔径';
            app.Label_20 = uilabel(app.Receive_Aperture_Parameters);
            app.Label_20.Text = '深度';
            app.RevAperDepth = uieditfield(app.Receive_Aperture_Parameters, 'text');
            app.RevAperDepth.Value = '0 0.04';
            app.FEditField_3Label = uilabel(app.Receive_Aperture_Parameters);
            app.FEditField_3Label.Text = 'F#';
            app.RevAperFnum = uieditfield(app.Receive_Aperture_Parameters, 'text');
            app.RevAperFnum.Value = '1.2 1';
            app.Label_21 = uilabel(app.Receive_Aperture_Parameters);
            app.Label_21.Text = '变迹';
            app.RevApodWin = uidropdown(app.Receive_Aperture_Parameters);
            app.RevApodWin.Items = {'hamming', 'hanning', 'gaussian', 'rect'};
            app.RevApodWin.Value = 'hamming';
            app.EditField_9Label_4 = uilabel(app.Receive_Aperture_Parameters);
            app.EditField_9Label_4.Text = '最小孔径';
            app.MinRevAper = uieditfield(app.Receive_Aperture_Parameters, 'text');
            app.MinRevAper.ValueChangedFcn = createCallbackFcn(app, @MinRevAperValueChanged, true);
            app.MinRevAper.Value = '16';
            app.EditField_9Label_5 = uilabel(app.Receive_Aperture_Parameters);
            app.EditField_9Label_5.Text = '最大孔径';
            app.MaxRevAper = uieditfield(app.Receive_Aperture_Parameters, 'text');
            app.MaxRevAper.ValueChangedFcn = createCallbackFcn(app, @MaxRevAperValueChanged, true);
            app.MaxRevAper.Value = '128';
            app.Label_7 = uilabel(app.Frontend);
            app.Label_7.Text = '探头';
            app.Probe = uidropdown(app.Frontend);
            app.Probe.Items = {'L5-10', 'L10-20', 'L2-6'};
            app.Probe.Value = 'L10-20';
            app.Label_8 = uilabel(app.Frontend);
            app.Label_8.Text = '声速';
            app.SoundSpeed = uieditfield(app.Frontend, 'text');
            app.SoundSpeed.ValueChangedFcn = createCallbackFcn(app, @SoundSpeedValueChanged, true);
            app.SoundSpeed.Value = '1540';
            app.Label_9 = uilabel(app.Frontend);
            app.Label_9.Text = '采样率';
            app.SampleRate = uieditfield(app.Frontend, 'text');
            app.SampleRate.CharacterLimits = [0 80000000];
            app.SampleRate.ValueChangedFcn = createCallbackFcn(app, @SampleRateValueChanged, true);
            app.SampleRate.Value = '80000000';
            app.Label_10 = uilabel(app.Frontend);
            app.Label_10.Text = 'PRF';
            app.CaptureSpeed = uieditfield(app.Frontend, 'text');
            app.CaptureSpeed.ValueChangedFcn = createCallbackFcn(app, @CaptureSpeedValueChanged, true);
            app.CaptureSpeed.Value = '35';
            app.Label_11 = uilabel(app.Frontend);
            app.Label_11.Text = '采集数量';
            app.CaptureFrames = uieditfield(app.Frontend, 'text');
            app.CaptureFrames.ValueChangedFcn = createCallbackFcn(app, @CaptureFramesValueChanged, true);
            app.CaptureFrames.Value = '1';
            app.Label_12 = uilabel(app.Frontend);
            app.Label_12.Text = '波束类型';
            app.BeamType = uidropdown(app.Frontend);
            app.BeamType.Items = {'Plane Wave', 'Focus Wave'};
            app.BeamType.ValueChangedFcn = createCallbackFcn(app, @BeamTypeValueChanged, true);
            app.BeamType.Value = 'Plane Wave';
            app.EmitPlaneAngle = uieditfield(app.Frontend, 'text');
            app.EmitPlaneAngle.Visible = 'off';
            app.EmitPlaneAngle.Value = '-5 -3 0 3 5';
            app.Label_14 = uilabel(app.Frontend);
            app.Label_14.Text = '通道';
            app.MachineChannel = uieditfield(app.Frontend, 'text');
            app.MachineChannel.CharacterLimits = [0 80000000];
            app.MachineChannel.Value = '128';
            app.EmitPlaneAngleLabel = uilabel(app.Frontend);
            app.EmitPlaneAngleLabel.Visible = 'off';
            app.EmitPlaneAngleLabel.Text = '发射角度';
            app.EmitFocusLabel = uilabel(app.Frontend);
            app.EmitFocusLabel.Text = '发射焦点';
            app.Run = uibutton(app.Frontend, 'push');
            app.Run.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.Run.Text = '运行';
            app.Stop = uibutton(app.Frontend, 'push');
            app.Stop.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.Stop.Enable = 'off';
            app.Stop.Visible = 'off';
            app.Stop.Text = '停止';
            app.PlaneWaveImageRegion = uipanel(app.Frontend);
            app.PlaneWaveImageRegion.Title = '成像区域';
            app.Z_Panel = uipanel(app.PlaneWaveImageRegion);
            app.Z_Panel.Title = '纵向';
            app.EditField_9Label_29 = uilabel(app.Z_Panel);
            app.EditField_9Label_29.Text = '起始';
            app.Pw_start_z = uieditfield(app.Z_Panel, 'text');
            app.Pw_start_z.Value = '0.01';
            app.EditField_9Label_30 = uilabel(app.Z_Panel);
            app.EditField_9Label_30.Text = '结束';
            app.Pw_end_z = uieditfield(app.Z_Panel, 'text');
            app.Pw_end_z.Value = '0.04';
            app.EditField_9Label_31 = uilabel(app.Z_Panel);
            app.EditField_9Label_31.Text = '间距';
            app.Pw_step_z = uieditfield(app.Z_Panel, 'text');
            app.Pw_step_z.Value = '1.925e-5';
            app.X_Panel = uipanel(app.PlaneWaveImageRegion);
            app.X_Panel.Title = '横向';
            app.EditField_9Label_32 = uilabel(app.X_Panel);
            app.EditField_9Label_32.Text = '起始';
            app.Pw_start_x = uieditfield(app.X_Panel, 'text');
            app.Pw_start_x.Value = '-0.0192';
            app.EditField_9Label_33 = uilabel(app.X_Panel);
            app.EditField_9Label_33.Text = '结束';
            app.Pw_end_x = uieditfield(app.X_Panel, 'text');
            app.Pw_end_x.Value = '0.0192';
            app.EditField_9Label_34 = uilabel(app.X_Panel);
            app.EditField_9Label_34.Text = '间距';
            app.Pw_step_x = uieditfield(app.X_Panel, 'text');
            app.Pw_step_x.Value = '3e-4';
            app.ImageRegion_PlaneWave_Auto = uicheckbox(app.PlaneWaveImageRegion);
            app.ImageRegion_PlaneWave_Auto.ValueChangedFcn = createCallbackFcn(app, @ImageRegion_PlaneWave_AutoValueChanged, true);
            app.ImageRegion_PlaneWave_Auto.Text = '自动计算';
            app.Post_Process_Parameters = uipanel(app.BTab);
            app.Post_Process_Parameters.Title = '后处理';
            app.Label_22 = uilabel(app.Post_Process_Parameters);
            app.Label_22.Text = '空间平滑';
            app.SpatialSmoothLevel = uidropdown(app.Post_Process_Parameters);
            app.SpatialSmoothLevel.Items = {'0', '1', '2', '3', '4'};
            app.SpatialSmoothLevel.Value = '0';
            app.Label_23 = uilabel(app.Post_Process_Parameters);
            app.Label_23.Text = '动态范围';
            app.DynamicRange = uieditfield(app.Post_Process_Parameters, 'text');
            app.DynamicRange.ValueChangedFcn = createCallbackFcn(app, @DynamicRangeValueChanged, true);
            app.DynamicRange.Value = '60';
            app.GrayMapIdx = uidropdown(app.Post_Process_Parameters);
            app.GrayMapIdx.Items = {'0', '1', '2'};
            app.GrayMapIdx.Value = '0';
            app.Label_24 = uilabel(app.Post_Process_Parameters);
            app.Label_24.Text = '  灰阶';
            app.Label_25 = uilabel(app.Post_Process_Parameters);
            app.Label_25.Text = '  伪彩';
            app.TimtMapIdx = uidropdown(app.Post_Process_Parameters);
            app.TimtMapIdx.Items = {'0', '1', '2'};
            app.TimtMapIdx.Value = '0';
            app.Label_26 = uilabel(app.Post_Process_Parameters);
            app.Label_26.Text = ' 扫描变换';
            app.DscFlag = uicheckbox(app.Post_Process_Parameters);
            app.DscFlag.Text = '';
            app.DscFlag.Value = true;
            app.PostProcessRun = uibutton(app.Post_Process_Parameters, 'push');
            app.PostProcessRun.ButtonPushedFcn = createCallbackFcn(app, @PostProcessRunButtonPushed, true);
            app.PostProcessRun.Visible = 'off';
            app.PostProcessRun.Text = '后处理';
            app.Gain_Parameters = uipanel(app.Post_Process_Parameters);
            app.Gain_Parameters.Title = '增益';
            app.TGC_Parameters = uipanel(app.Gain_Parameters);
            app.TGC_Parameters.Title = 'TGC';
            app.SliderGain0 = uislider(app.TGC_Parameters);
            app.SliderGain0.Limits = [0 255];
            app.SliderGain0.MajorTicks = [];
            app.SliderGain0.MinorTicks = [];
            app.SliderGain0.Value = 128;
            app.SliderGain1 = uislider(app.TGC_Parameters);
            app.SliderGain1.Limits = [0 255];
            app.SliderGain1.MajorTicks = [];
            app.SliderGain1.MinorTicks = [];
            app.SliderGain1.Value = 128;
            app.SliderGain2 = uislider(app.TGC_Parameters);
            app.SliderGain2.Limits = [0 255];
            app.SliderGain2.MajorTicks = [];
            app.SliderGain2.MinorTicks = [];
            app.SliderGain2.Value = 128;
            app.SliderGain3 = uislider(app.TGC_Parameters);
            app.SliderGain3.Limits = [0 255];
            app.SliderGain3.MajorTicks = [];
            app.SliderGain3.MinorTicks = [];
            app.SliderGain3.Value = 128;
            app.SliderGain4 = uislider(app.TGC_Parameters);
            app.SliderGain4.Limits = [0 255];
            app.SliderGain4.MajorTicks = [];
            app.SliderGain4.MinorTicks = [];
            app.SliderGain4.Value = 128;
            app.SliderGain5 = uislider(app.TGC_Parameters);
            app.SliderGain5.Limits = [0 255];
            app.SliderGain5.MajorTicks = [];
            app.SliderGain5.MinorTicks = [];
            app.SliderGain5.Value = 128;
            app.KnobGain = uiknob(app.Gain_Parameters, 'continuous');
            app.KnobGain.MinorTicks = [0 2 4 6 8 10 12 14 16 18 20 22 24 26 28 30 32 34 36 38 40 42 44 46 48 50 52 54 56 58 60 62 64 66 68 70 72 74 76 78 80 82 84 86 88 90 92 94 96 98 100];
            app.KnobGain.Value = 50;
            app.Label_27 = uilabel(app.Gain_Parameters);
            app.Label_27.Text = '全局增益';
            app.GlobalDigitalGain = uieditfield(app.Gain_Parameters, 'text');
            app.GlobalDigitalGain.ValueChangedFcn = createCallbackFcn(app, @GlobalDigitalGainValueChanged, true);
            app.GlobalDigitalGain.Value = '-4';
            app.EditField_17Label = uilabel(app.Gain_Parameters);
            app.EditField_17Label.Text = '深度';
            app.DigitalGainDepth = uieditfield(app.Gain_Parameters, 'text');
            app.DigitalGainDepth.Value = '0 0.005 0.01 0.015 0.02 0.03 0.04';
            app.Label_28 = uilabel(app.Gain_Parameters);
            app.Label_28.Text = '增益';
            app.DigitalGainValue = uieditfield(app.Gain_Parameters, 'text');
            app.DigitalGainValue.ValueChangedFcn = createCallbackFcn(app, @DigitalGainChanged, true);
            app.DigitalGainValue.Value = '-7 -7 -9 -12 -12 -2 -2';
            app.Demodulation_Parameters = uipanel(app.Post_Process_Parameters);
            app.Demodulation_Parameters.Title = '解调滤波';
            app.EditField_11Label = uilabel(app.Demodulation_Parameters);
            app.EditField_11Label.Text = '深度';
            app.DemodulationDepth = uieditfield(app.Demodulation_Parameters, 'text');
            app.DemodulationDepth.Value = '0 0.04';
            app.EditField_12Label = uilabel(app.Demodulation_Parameters);
            app.EditField_12Label.Text = '频率';
            app.DemodulationValue = uieditfield(app.Demodulation_Parameters, 'text');
            app.DemodulationValue.Value = '15000000 15000000';
            app.EditField_11Label_2 = uilabel(app.Demodulation_Parameters);
            app.EditField_11Label_2.Text = '滤波器阶数';
            app.FilterOrder = uieditfield(app.Demodulation_Parameters, 'text');
            app.FilterOrder.ValueChangedFcn = createCallbackFcn(app, @FilterOrderValueChanged, true);
            app.FilterOrder.Value = '128';
            app.EditField_12Label_2 = uilabel(app.Demodulation_Parameters);
            app.EditField_12Label_2.Text = '滤波器系数 ';
            app.FilterCoef = uieditfield(app.Demodulation_Parameters, 'text');
            app.FilterCoef.ValueChangedFcn = createCallbackFcn(app, @FilterCoefValueChanged, true);
            app.FilterCoef.Value = '0.15';
            app.Label_29 = uilabel(app.Demodulation_Parameters);
            app.Label_29.Text = '滤波器类型';
            app.FilterType = uidropdown(app.Demodulation_Parameters);
            app.FilterType.Items = {'hamming', 'hanning', 'gaussian'};
            app.FilterType.Value = 'hamming';
            app.AllmagShow = uipanel(app.BTab);
            app.AllmagShow.Title = '显示';
            app.FreSpectrumImage = uiaxes(app.AllmagShow);
            app.FreSpectrumImage.XTick = [];
            app.FreSpectrumImage.YTick = [];
            app.ImageShow = uiaxes(app.AllmagShow);
            app.ImageShow.XTick = [];
            app.ImageShow.YTick = [];
            app.ImageType = uidropdown(app.AllmagShow);
            app.ImageType.Items = {'ADC', 'RF', 'B'};
            app.ImageType.ValueChangedFcn = createCallbackFcn(app, @ImageTypeValueChanged, true);
            app.ImageType.Value = 'ADC';
            app.ImgaeSlider = uislider(app.AllmagShow);
            app.ImgaeSlider.Limits = [0 100000];
            app.ImgaeSlider.MajorTicks = [0 9];
            app.ImgaeSlider.MajorTickLabels = {''};
            app.ImgaeSlider.ValueChangedFcn = createCallbackFcn(app, @ImgaeSliderValueChanged, true);
            app.ImgaeSlider.MinorTicks = [];
            app.ImageLoc = uieditfield(app.AllmagShow, 'text');
            app.ImageLoc.CharacterLimits = [0 6];
            app.ImageLoc.ValueChangedFcn = createCallbackFcn(app, @ImageLocValueChanged, true);
            app.ImageLoc.Value = '0';
            app.ImageCheck = uicheckbox(app.AllmagShow);
            app.ImageCheck.ValueChangedFcn = createCallbackFcn(app, @ImageCheckValueChanged, true);
            app.ImageCheck.Text = '';
            app.Label = uilabel(app.AllmagShow);
            app.Label.Text = ' 线位置';
            app.FreSpectrumLRange = uieditfield(app.AllmagShow, 'text');
            app.FreSpectrumLRange.ValueChangedFcn = createCallbackFcn(app, @FreSpectrumLRangeValueChanged, true);
            app.FreSpectrumLRange.Value = '2:64';
            app.Label_2 = uilabel(app.AllmagShow);
            app.Label_2.Text = '深度位置';
            app.FreSpectrumDRange = uieditfield(app.AllmagShow, 'text');
            app.FreSpectrumDRange.ValueChangedFcn = createCallbackFcn(app, @FreSpectrumDRangeValueChanged, true);
            app.FreSpectrumDRange.Value = '800:1000';
            app.FreSpectrumSwitch = uicheckbox(app.AllmagShow);
            app.FreSpectrumSwitch.ValueChangedFcn = createCallbackFcn(app, @FreSpectrumSwitchValueChanged, true);
            app.FreSpectrumSwitch.Text = '';
            app.Label_3 = uilabel(app.AllmagShow);
            app.Label_3.Text = '频谱分析';
            app.FreSpectrumChannel = uieditfield(app.AllmagShow, 'text');
            app.FreSpectrumChannel.ValueChangedFcn = createCallbackFcn(app, @FreSpectrumChannelValueChanged, true);
            app.FreSpectrumChannel.Visible = 'off';
            app.FreSpectrumChannel.Value = '2:64';
            app.FreSpectrumCLabel = uilabel(app.AllmagShow);
            app.FreSpectrumCLabel.Visible = 'off';
            app.FreSpectrumCLabel.Text = '通道位置';
            app.FUSTab = uitab(app.TabGroup);
            app.FUSTab.Title = 'FUS';
            app.FUSTab.ButtonDownFcn = createCallbackFcn(app, @FUSTabButtonDown, true);
            app.Frontend_UPW = uipanel(app.FUSTab);
            app.Frontend_UPW.Title = '前端';
            app.Label_36 = uilabel(app.Frontend_UPW);
            app.Label_36.Text = '探头';
            app.Probe_UPW = uidropdown(app.Frontend_UPW);
            app.Probe_UPW.Items = {'L5-10', 'L10-20', 'L2-6'};
            app.Probe_UPW.Value = 'L10-20';
            app.Label_37 = uilabel(app.Frontend_UPW);
            app.Label_37.Text = '声速';
            app.SoundSpeed_UPW = uieditfield(app.Frontend_UPW, 'text');
            app.SoundSpeed_UPW.ValueChangedFcn = createCallbackFcn(app, @SoundSpeed_UPWValueChanged, true);
            app.SoundSpeed_UPW.Value = '1540';
            app.Label_38 = uilabel(app.Frontend_UPW);
            app.Label_38.Text = '采样率';
            app.SampleRate_UPW = uieditfield(app.Frontend_UPW, 'text');
            app.SampleRate_UPW.CharacterLimits = [0 80000000];
            app.SampleRate_UPW.ValueChangedFcn = createCallbackFcn(app, @SampleRate_UPWValueChanged, true);
            app.SampleRate_UPW.Value = '80000000';
            app.PlaneWaveImageRegion_UPW = uipanel(app.Frontend_UPW);
            app.PlaneWaveImageRegion_UPW.Title = '成像区域';
            app.UPW_Z_Panel = uipanel(app.PlaneWaveImageRegion_UPW);
            app.UPW_Z_Panel.Title = '纵向';
            app.EditField_9Label_14 = uilabel(app.UPW_Z_Panel);
            app.EditField_9Label_14.Text = '起始';
            app.Pw_start_z_UPW = uieditfield(app.UPW_Z_Panel, 'text');
            app.Pw_start_z_UPW.Value = '0.001';
            app.EditField_9Label_18 = uilabel(app.UPW_Z_Panel);
            app.EditField_9Label_18.Text = '结束';
            app.Pw_end_z_UPW = uieditfield(app.UPW_Z_Panel, 'text');
            app.Pw_end_z_UPW.Value = '0.015';
            app.EditField_9Label_19 = uilabel(app.UPW_Z_Panel);
            app.EditField_9Label_19.Text = '间距';
            app.Pw_step_z_UPW = uieditfield(app.UPW_Z_Panel, 'text');
            app.Pw_step_z_UPW.Value = '1.925e-5';
            app.UPW_X_Panel = uipanel(app.PlaneWaveImageRegion_UPW);
            app.UPW_X_Panel.Title = '横向';
            app.EditField_9Label_20 = uilabel(app.UPW_X_Panel);
            app.EditField_9Label_20.Text = '起始';
            app.Pw_start_x_UPW = uieditfield(app.UPW_X_Panel, 'text');
            app.Pw_start_x_UPW.Value = '-0.0192';
            app.EditField_9Label_21 = uilabel(app.UPW_X_Panel);
            app.EditField_9Label_21.Text = '结束';
            app.Pw_end_x_UPW = uieditfield(app.UPW_X_Panel, 'text');
            app.Pw_end_x_UPW.Value = '0.0192';
            app.EditField_9Label_22 = uilabel(app.UPW_X_Panel);
            app.EditField_9Label_22.Text = '间距';
            app.Pw_step_x_UPW = uieditfield(app.UPW_X_Panel, 'text');
            app.Pw_step_x_UPW.Value = '3e-4';
            app.ImageRegion_UPW_Auto = uicheckbox(app.PlaneWaveImageRegion_UPW);
            app.ImageRegion_UPW_Auto.ValueChangedFcn = createCallbackFcn(app, @ImageRegion_UPW_AutoValueChanged, true);
            app.ImageRegion_UPW_Auto.Text = '自动计算';
            app.Label_43 = uilabel(app.Frontend_UPW);
            app.Label_43.Text = '通道';
            app.MachineChannel_UPW = uieditfield(app.Frontend_UPW, 'text');
            app.MachineChannel_UPW.CharacterLimits = [0 80000000];
            app.MachineChannel_UPW.Value = '128';
            app.Emit_Wave_Parameters_UPW = uipanel(app.Frontend_UPW);
            app.Emit_Wave_Parameters_UPW.Title = '发射波形';
            app.Label_44 = uilabel(app.Emit_Wave_Parameters_UPW);
            app.Label_44.Text = '电压';
            app.EmitVol_UPW = uieditfield(app.Emit_Wave_Parameters_UPW, 'text');
            app.EmitVol_UPW.ValueChangedFcn = createCallbackFcn(app, @EmitVol_UPWValueChanged, true);
            app.EmitVol_UPW.Value = '25';
            app.Label_45 = uilabel(app.Emit_Wave_Parameters_UPW);
            app.Label_45.Text = '周期';
            app.EmitPulseNum_UPW = uieditfield(app.Emit_Wave_Parameters_UPW, 'text');
            app.EmitPulseNum_UPW.ValueChangedFcn = createCallbackFcn(app, @EmitPulseNum_UPWValueChanged, true);
            app.EmitPulseNum_UPW.Value = '1';
            app.Label_46 = uilabel(app.Emit_Wave_Parameters_UPW);
            app.Label_46.Text = '占空比';
            app.EmitDuration_UPW = uieditfield(app.Emit_Wave_Parameters_UPW, 'text');
            app.EmitDuration_UPW.ValueChangedFcn = createCallbackFcn(app, @EmitDuration_UPWValueChanged, true);
            app.EmitDuration_UPW.Value = '1';
            app.Label_47 = uilabel(app.Emit_Wave_Parameters_UPW);
            app.Label_47.Text = '频率';
            app.EmitPulseFre_UPW = uieditfield(app.Emit_Wave_Parameters_UPW, 'text');
            app.EmitPulseFre_UPW.Value = '15000000';
            app.Label_48 = uilabel(app.Emit_Wave_Parameters_UPW);
            app.Label_48.Text = '极性';
            app.EmitPolarity_UPW = uieditfield(app.Emit_Wave_Parameters_UPW, 'text');
            app.EmitPolarity_UPW.ValueChangedFcn = createCallbackFcn(app, @EmitPolarity_UPWValueChanged, true);
            app.EmitPolarity_UPW.Value = '0';
            app.CaptureTimes_UPW = uieditfield(app.Frontend_UPW, 'text');
            app.CaptureTimes_UPW.Value = '2';
            app.Label_51 = uilabel(app.Frontend_UPW);
            app.Label_51.Text = '采集时长';
            app.Label_39 = uilabel(app.Frontend_UPW);
            app.Label_39.Text = 'PRF';
            app.CaptureSpeed_UPW = uieditfield(app.Frontend_UPW, 'text');
            app.CaptureSpeed_UPW.ValueChangedFcn = createCallbackFcn(app, @CaptureSpeed_UPWValueChanged, true);
            app.CaptureSpeed_UPW.Value = '5000';
            app.Label_34 = uilabel(app.Frontend_UPW);
            app.Label_34.Text = '成像深度';
            app.ImageDepth_UPW = uieditfield(app.Frontend_UPW, 'text');
            app.ImageDepth_UPW.ValueChangedFcn = createCallbackFcn(app, @ImageDepth_UPWValueChanged, true);
            app.ImageDepth_UPW.Value = '0.015';
            app.Label_42 = uilabel(app.Frontend_UPW);
            app.Label_42.Text = '发射角度';
            app.EmitPlaneAngle_UPW = uieditfield(app.Frontend_UPW, 'text');
            app.EmitPlaneAngle_UPW.ValueChangedFcn = createCallbackFcn(app, @EmitPlaneAngle_UPWValueChanged, true);
            app.EmitPlaneAngle_UPW.Value = '-15	-12.8571428571429	-10.7142857142857	-8.57142857142857	-6.42857142857143	-4.28571428571429	-2.14285714285714	0	2.14285714285714	4.28571428571429	6.42857142857143	8.57142857142857	10.7142857142857	12.8571428571429	15';
            app.Receive_Aperture_Parameters_UPW = uipanel(app.Frontend_UPW);
            app.Receive_Aperture_Parameters_UPW.Title = '接收孔径';
            app.FEditField_3Label_2 = uilabel(app.Receive_Aperture_Parameters_UPW);
            app.FEditField_3Label_2.Text = 'F#';
            app.RevAperFnum_UPW = uieditfield(app.Receive_Aperture_Parameters_UPW, 'text');
            app.RevAperFnum_UPW.Value = '0.8';
            app.Label_50 = uilabel(app.Receive_Aperture_Parameters_UPW);
            app.Label_50.Text = '变迹';
            app.RevApodWin_UPW = uidropdown(app.Receive_Aperture_Parameters_UPW);
            app.RevApodWin_UPW.Items = {'hamming', 'hanning', 'gaussian', 'rect'};
            app.RevApodWin_UPW.Value = 'hamming';
            app.EditField_9Label_12 = uilabel(app.Receive_Aperture_Parameters_UPW);
            app.EditField_9Label_12.Text = '最小孔径';
            app.MinRevAper_UPW = uieditfield(app.Receive_Aperture_Parameters_UPW, 'text');
            app.MinRevAper_UPW.ValueChangedFcn = createCallbackFcn(app, @MinRevAper_UPWValueChanged, true);
            app.MinRevAper_UPW.Value = '16';
            app.EditField_9Label_13 = uilabel(app.Receive_Aperture_Parameters_UPW);
            app.EditField_9Label_13.Text = '最大孔径';
            app.MaxRevAper_UPW = uieditfield(app.Receive_Aperture_Parameters_UPW, 'text');
            app.MaxRevAper_UPW.ValueChangedFcn = createCallbackFcn(app, @MaxRevAper_UPWValueChanged, true);
            app.MaxRevAper_UPW.Value = '128';
            app.FEditField_3Label_3 = uilabel(app.Receive_Aperture_Parameters_UPW);
            app.FEditField_3Label_3.Text = '深度';
            app.RevAperDepth_UPW = uieditfield(app.Receive_Aperture_Parameters_UPW, 'text');
            app.RevAperDepth_UPW.Value = '0 0.04';
            app.Run_UPW = uibutton(app.Frontend_UPW, 'push');
            app.Run_UPW.ButtonPushedFcn = createCallbackFcn(app, @Run_UPWButtonPushed, true);
            app.Run_UPW.Text = '运行';
            app.SaveADC_UPW = uicheckbox(app.Frontend_UPW);
            app.SaveADC_UPW.Text = '';
            app.Label_52 = uilabel(app.Frontend_UPW);
            app.Label_52.Text = '保存';
            app.ShowImg_UPW = uicheckbox(app.Frontend_UPW);
            app.ShowImg_UPW.Text = '';
            app.Label_53 = uilabel(app.Frontend_UPW);
            app.Label_53.Text = '实时显示';
            app.Label_54 = uilabel(app.Frontend_UPW);
            app.Label_54.Text = '帧率';
            app.FrameRate_UPW = uieditfield(app.Frontend_UPW, 'text');
            app.FrameRate_UPW.ValueChangedFcn = createCallbackFcn(app, @FrameRate_UPWValueChanged, true);
            app.FrameRate_UPW.Value = '35';
            app.Post_Process_Parameters_UPW = uipanel(app.FUSTab);
            app.Post_Process_Parameters_UPW.Title = '数据处理';
            app.Label_56 = uilabel(app.Post_Process_Parameters_UPW);
            app.Label_56.Text = '动态范围';
            app.DynamicRange_UPW = uieditfield(app.Post_Process_Parameters_UPW, 'text');
            app.DynamicRange_UPW.Value = '60';
            app.PostProcessRun_UPW = uibutton(app.Post_Process_Parameters_UPW, 'push');
            app.PostProcessRun_UPW.ButtonPushedFcn = createCallbackFcn(app, @PostProcessRun_UPWButtonPushed, true);
            app.PostProcessRun_UPW.Visible = 'off';
            app.PostProcessRun_UPW.Text = '后处理';
            app.Demodulation_Parameters_UPW = uipanel(app.Post_Process_Parameters_UPW);
            app.Demodulation_Parameters_UPW.Title = '解调滤波';
            app.EditField_11Label_3 = uilabel(app.Demodulation_Parameters_UPW);
            app.EditField_11Label_3.Text = '深度';
            app.DemodulationDepth_UPW = uieditfield(app.Demodulation_Parameters_UPW, 'text');
            app.DemodulationDepth_UPW.Value = '0 0.04';
            app.EditField_12Label_3 = uilabel(app.Demodulation_Parameters_UPW);
            app.EditField_12Label_3.Text = '频率';
            app.DemodulationValue_UPW = uieditfield(app.Demodulation_Parameters_UPW, 'text');
            app.DemodulationValue_UPW.Value = '15000000 15000000';
            app.EditField_11Label_4 = uilabel(app.Demodulation_Parameters_UPW);
            app.EditField_11Label_4.Text = '滤波器阶数';
            app.FilterOrder_UPW = uieditfield(app.Demodulation_Parameters_UPW, 'text');
            app.FilterOrder_UPW.ValueChangedFcn = createCallbackFcn(app, @FilterOrder_UPWValueChanged, true);
            app.FilterOrder_UPW.Value = '128';
            app.EditField_12Label_4 = uilabel(app.Demodulation_Parameters_UPW);
            app.EditField_12Label_4.Text = '滤波器系数 ';
            app.FilterCoef_UPW = uieditfield(app.Demodulation_Parameters_UPW, 'text');
            app.FilterCoef_UPW.Value = '0.15';
            app.Label_62 = uilabel(app.Demodulation_Parameters_UPW);
            app.Label_62.Text = '滤波器类型';
            app.FilterType_UPW = uidropdown(app.Demodulation_Parameters_UPW);
            app.FilterType_UPW.Items = {'hamming', 'hanning', 'gaussian'};
            app.FilterType_UPW.Value = 'hamming';
            app.SVD_Parameters_UPW = uipanel(app.Post_Process_Parameters_UPW);
            app.SVD_Parameters_UPW.Title = 'SVD';
            app.EditField_11Label_5 = uilabel(app.SVD_Parameters_UPW);
            app.EditField_11Label_5.Text = 'L';
            app.Svd_L = uieditfield(app.SVD_Parameters_UPW, 'text');
            app.Svd_L.Value = '100';
            app.Svd_Check = uicheckbox(app.SVD_Parameters_UPW);
            app.Svd_Check.Visible = 'off';
            app.Svd_Check.Text = '';
            app.Label_63 = uilabel(app.SVD_Parameters_UPW);
            app.Label_63.Visible = 'off';
            app.Label_63.Text = '特征值';
            app.EditField_11Label_6 = uilabel(app.SVD_Parameters_UPW);
            app.EditField_11Label_6.Text = 'H';
            app.Svd_H = uieditfield(app.SVD_Parameters_UPW, 'text');
            app.Svd_H.Value = '200';
            app.EditField_11Label_10 = uilabel(app.SVD_Parameters_UPW);
            app.EditField_11Label_10.Text = 'Packsize';
            app.Svd_Packsize = uieditfield(app.SVD_Parameters_UPW, 'text');
            app.Svd_Packsize.Value = '10';
            app.Run_SVD_UPW = uibutton(app.SVD_Parameters_UPW, 'push');
            app.Run_SVD_UPW.ButtonPushedFcn = createCallbackFcn(app, @Run_SVD_UPWButtonPushed, true);
            app.Run_SVD_UPW.Text = '滤波';
            app.SavePow_UPW = uicheckbox(app.SVD_Parameters_UPW);
            app.SavePow_UPW.ValueChangedFcn = createCallbackFcn(app, @SavePow_UPWValueChanged, true);
            app.SavePow_UPW.Text = '';
            app.Label_66 = uilabel(app.SVD_Parameters_UPW);
            app.Label_66.Text = '保存';
            app.BF_Parameters_UPW = uipanel(app.Post_Process_Parameters_UPW);
            app.BF_Parameters_UPW.Title = '波束合成';
            app.EditField_11Label_7 = uilabel(app.BF_Parameters_UPW);
            app.EditField_11Label_7.Text = '起始帧';
            app.BF_FrameStart_UPW = uieditfield(app.BF_Parameters_UPW, 'text');
            app.BF_FrameStart_UPW.ValueChangedFcn = createCallbackFcn(app, @BF_FrameStart_UPWValueChanged, true);
            app.BF_FrameStart_UPW.Value = '10';
            app.EditField_11Label_8 = uilabel(app.BF_Parameters_UPW);
            app.EditField_11Label_8.Text = '结束帧';
            app.BF_FrameEnd_UPW = uieditfield(app.BF_Parameters_UPW, 'text');
            app.BF_FrameEnd_UPW.ValueChangedFcn = createCallbackFcn(app, @BF_FrameEnd_UPWValueChanged, true);
            app.BF_FrameEnd_UPW.Value = '50';
            app.EditField_11Label_9 = uilabel(app.BF_Parameters_UPW);
            app.EditField_11Label_9.Text = '数据包数';
            app.BF_Data_PackSize_UPW = uieditfield(app.BF_Parameters_UPW, 'text');
            app.BF_Data_PackSize_UPW.ValueChangedFcn = createCallbackFcn(app, @BF_Data_PackSize_UPWValueChanged, true);
            app.BF_Data_PackSize_UPW.Value = '10';
            app.BF_IQ_UPW_Check = uicheckbox(app.BF_Parameters_UPW);
            app.BF_IQ_UPW_Check.Enable = 'off';
            app.BF_IQ_UPW_Check.Visible = 'off';
            app.BF_IQ_UPW_Check.Text = '';
            app.Label_64 = uilabel(app.BF_Parameters_UPW);
            app.Label_64.Enable = 'off';
            app.Label_64.Visible = 'off';
            app.Label_64.Text = 'IQ BF';
            app.Run_BF_UPW = uibutton(app.BF_Parameters_UPW, 'push');
            app.Run_BF_UPW.ButtonPushedFcn = createCallbackFcn(app, @Run_BF_UPWButtonPushed, true);
            app.Run_BF_UPW.Text = '运行';
            app.UPWColorROIPanel = uipanel(app.Post_Process_Parameters_UPW);
            app.UPWColorROIPanel.Title = '血流成像区域';
            app.ColorROI_UPW_Check = uicheckbox(app.UPWColorROIPanel);
            app.ColorROI_UPW_Check.ValueChangedFcn = createCallbackFcn(app, @ColorROI_UPW_CheckValueChanged, true);
            app.ColorROI_UPW_Check.Text = '';
            app.Label_65 = uilabel(app.UPWColorROIPanel);
            app.Label_65.Text = 'ROI';
            app.ColorROI_UPW_TextArea = uitextarea(app.UPWColorROIPanel);
            app.ColorROI_UPW_TextArea.Enable = 'off';
            app.AllmagShow_UPW = uipanel(app.FUSTab);
            app.AllmagShow_UPW.Title = '显示';
            app.ImageShow_UPW = uiaxes(app.AllmagShow_UPW);
            app.ImageShow_UPW.XTick = [];
            app.ImageShow_UPW.YTick = [];
            app.ImageShow_UPW_2 = uiaxes(app.AllmagShow_UPW);
            app.ImageShow_UPW_2.XTick = [];
            app.ImageShow_UPW_2.YTick = [];
            % Show the figure after all components are created
            app.SignalProcessUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalProcess

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SignalProcessUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SignalProcessUIFigure)
        end
    end
end